classdef audioMic_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                  matlab.ui.Figure
        AudiowavePanel           matlab.ui.container.Panel
        UIAxes                   matlab.ui.control.UIAxes
        Panel_2                  matlab.ui.container.Panel
        TriggerListListBoxLabel  matlab.ui.control.Label
        TriggerListListBox       matlab.ui.control.ListBox
        Button_11                matlab.ui.control.Button
        Button_12                matlab.ui.control.Button
        Button_13                matlab.ui.control.Button
        EditField                matlab.ui.control.EditField
        InfoPanel                matlab.ui.container.Panel
        Label                    matlab.ui.control.Label
        Info                     matlab.ui.control.TextArea
        Panel                    matlab.ui.container.Panel
        Button_2                 matlab.ui.control.Button
        ButtonGroup              matlab.ui.container.ButtonGroup
        SecButton                matlab.ui.control.RadioButton
        TriggerIDButton          matlab.ui.control.RadioButton
        TimescaleSpinnerLabel    matlab.ui.control.Label
        TimescaleSpinner         matlab.ui.control.Spinner
        ThresholdSpinnerLabel    matlab.ui.control.Label
        ThresholdSpinner         matlab.ui.control.Spinner
        Button_6                 matlab.ui.control.Button
        Button_7                 matlab.ui.control.Button
        Button_10                matlab.ui.control.Button
        JumptoSpinnerLabel       matlab.ui.control.Label
        JumptoSpinner            matlab.ui.control.Spinner
        Button_15                matlab.ui.control.Button
        Button_16                matlab.ui.control.Button
        Button_14                matlab.ui.control.Button
        RejectSpinnerLabel       matlab.ui.control.Label
        RejectSpinner            matlab.ui.control.Spinner
        CheckBox                 matlab.ui.control.CheckBox
        Button_18                matlab.ui.control.StateButton
        Button_19                matlab.ui.control.Button
        Slider                   matlab.ui.control.Slider
        TriggerIDLabel           matlab.ui.control.Label
    end

    
    properties (Access = public)
        anlg
        thrh % threshold
        onset_offset %
        win %current window
        trigID %
        ts
        fs
        trig;
        triggermark % plots
        wave % plots
        label
        labeltxt
        normic
        win_width
        t
        format
        player
        anno
        audiocs
        audiocs_b
        atSample
        audioline
        stim
        thrh2
        ups
        los
        peakloc
        min_silence
        stimplot
        stimtxt
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function audioMic_OpeningFcn(app, varargin)
            %             % This function has no output args, see OutputFcn.
            %             % hObject    handle to figure
            %             % eventdata  reserved - to be defined in a future version of MATLAB
            %             % handles    structure with handles and user data (see GUIDATA)
            %             % varargin   command line arguments to audioMic (see VARARGIN)
            %
            %             % Choose default command line output for audioMic
            %             app.output = hObject;
            %
            %             % Update handles structure
            %             guidata(hObject, handles);
            %load(file{1});%('global_Calculia_production_S18_126_E18-602_0010.mat');
            %load(file{2});%('PdioE18-602_0010_02.mat');
            %load(file{3});%('trialinfo_E18-602_0010.mat');
            try
                anlg = varargin{1};
            catch
                return;
            end
            
            %globalVar = varargin{2};
            
            fs = varargin{2};
            if nargin == 4
                trialinfo = varargin{3};
                app.stim = trialinfo.allonsets;
            else
                app.stim = [];
            end
            normic = anlg./(max(abs(anlg)));
            app.normic = normic;
            app.anlg = anlg;
            app.fs = fs;
            samples = 1000;
            app.player = audioplayer(normic,app.fs);
            app.atSample = 0.01;
            app.audiocs = 0.01;
            
            %set.TimerFcn=@timerCallback;
            %app.player.TimerFcn = @timerCallback;

            
            %% Define threshold and silence
            app.min_silence = 20000; % this depends on the task - it should be around 3 seconds
            
            %% Envelope
            [app.ups,app.los] = envelope(app.normic,samples,'rms');
            
            app.thrh = 0.2; % this probably depends on the microfone and session
            app.thrh2 = 0.8;
            
            %% Start and end points
            % find cut point start
            cut_point1 = diff(([0 app.ups]-[0 app.los])<app.thrh);
            cut_point1(cut_point1~=-1) = 0;
            
            % find cut point end
            cut_point2 = diff(([app.ups 0]-[app.los 0])<app.thrh);
            cut_point2(cut_point2~=1) = 0;
            
            % combine cutpoints
            cutpoints = abs(cut_point1) + cut_point2;
            
            %% Count the number of regions
            ct_fill = ~bwareaopen(~cutpoints, app.min_silence); % remove some intermediate zeros
            [L, numRegions] = bwlabel(ct_fill); % count regions of 1's
            %numRegions
            
            %% Calculate onsets
            ct_fill_0 = (ct_fill~=0);
            ct_fill_d = diff(ct_fill_0);
            start = find([ct_fill_0(1) ct_fill_d]==1)'; % Start index of each group
            finish = find([ct_fill_d -ct_fill_0(end)]==-1)'; % Last index of each group
            try
                [~,loc]=findpeaks(app.ups,'MinPeakHeight',app.thrh2,'minpeakdistance',3000); % Remove segments with peaks exceeding threshod 2
                
            catch
                loc = [];
                
            end
            app.peakloc = loc;
            n=1;
            remove = [];
            for i = 1:length(start)
                if ~isempty(intersect(start(i):finish(i),loc))
                    remove(n) = i;
                    n = n+1;
                end
            end
            start(remove) = [];
            finish(remove) = [];
            
            count = length(start);
            app.format = 1; %in seconds
            app.win = [0.001 length(anlg)/app.fs];%full window
            app.t = (1 : length(anlg))/app.fs;
            app.win_width=app.win(end);
            app.audiocs = 1;
            app.audiocs_b = 1;
            app.onset_offset = zeros(count,2);
            base = -1*ones(size(anlg));
            app.trig = base;
            app.label = strsplit(num2str(1:count));
            for i = 1:count
                app.onset_offset(i,:) = [ start(i) finish(i)] ;
                app.trig(start(i):finish(i)) = 1;
            end
            
            app.wave = plot(app.UIAxes,app.t,app.normic,'linewidth',1,'color',[0.35 0.35 0.35]);
            set(app.UIAxes,'linewidth',1,'fontsize',12,'ticklength',[0.05 0.05],'ytick',[])%'color',[0.94 0.94 0.94]);
            app.UIAxes.XLim=([0 app.win(end)]);
            app.UIAxes.YLim=([-1 1]);
            set(app.UIAxes,'nextplot','add');
            app.triggermark = area(app.UIAxes,app.t,app.trig,-1,'facealpha',0.3,'linestyle','none','facecolor',[0 0.45 0.74]);
            app.audioline = line(app.UIAxes,[app.audiocs app.audiocs],[-1 1],'color',[0.93 0.69 0.13],'linewidth',2);
            set(app.player,'TimerFcn',{@timerline,app.audioline}, 'TimerPeriod', 0.1);
            
            for i = 1:length(app.onset_offset)
                %plot(app.onset_offset{i}(1):app.onset_offset{i}(2),zeros(length(anlg(app.onset_offset{i}(1):app.onset_offset{i}(2))),1), 'LineWidth', 50, 'Color', 'red')
                app.labeltxt(i) = text(app.UIAxes,app.onset_offset(i,1)/app.fs,0, num2str(i), 'FontSize', 12,'color',[1 1 1]);
            end
            cm=[94 168 101;219 194 134;193 101 79]./255;
            for i = 1:size(app.stim,2)
                if i == size(app.stim,2)
                    app.stimplot(i) = stem(app.UIAxes,app.stim(:,i), ones(length(app.stim(:,1)),1), '.', 'linewidth', 1,'color',cm(i,:));
                else
                    app.stimplot(i) = plot(app.UIAxes,app.stim(:,i), ones(length(app.stim(:,1)),1), '.','color',cm(i,:),'markersize',15);
                end
            end
            for i = 1:size(app.stim,1)
                %plot(app.onset_offset{i}(1):app.onset_offset{i}(2),zeros(length(anlg(app.onset_offset{i}(1):app.onset_offset{i}(2))),1), 'LineWidth', 50, 'Color', 'red')
                app.stimtxt(i) = text(app.UIAxes,app.stim(i,end)-0.01,0.9, num2str(i), 'FontSize', 12,'color',[193 101 79]/255);
            end
            set(app.UIAxes,'nextplot','replace');
            app.UIAxes.XLabel.String=('Time (s)');
            app.UIAxes.YLabel.String='';
            app.trigID = 1;
            app.anno = cell(1,count);
            app.ts = [1 length(app.trig)];
            set(app.TimescaleSpinner,'Limits',[0.05 app.win(end)],'Value',app.win(end),'Step',10);
            set(app.Slider,'Limits',[0.001 app.win(end)],'Value',app.win(1),'MajorTicks',app.onset_offset(:,1)/app.fs,'MajorTickLabels',app.label);
            set(app.ThresholdSpinner,'Value',0.2,'Step',0.05);
            set(app.TriggerListListBox,'Items',strsplit(num2str(1:length(app.onset_offset))));
            set(app.JumptoSpinner,'Limits',[0.001 app.win(end)],'Value',0.001,'Step',5);
        end

        % Value changing function: TimescaleSpinner
        function TimescaleSpinnerValueChanging(app, event)
            changingValue = event.Value;
            if   event.Value<=10 && app.TimescaleSpinner.Step == 10
                set(app.TimescaleSpinner,'Step',1);
            elseif event.Value>10 && app.TimescaleSpinner.Step == 1
                set(app.TimescaleSpinner,'Step',10);
            end
            %app.win_width = event.Value;
            try
                app.win(end) = app.win(1) + changingValue;
                app.ts = round(app.win.*app.fs);
                id = find(app.onset_offset(:,1)>=app.ts(1));
                id = id(app.onset_offset(id,2)<=app.ts(end));
                if ~isempty(id)
                    app.trigID = id(1);
                end
                %plot_sig(app.UIAxes,app.win,app.normic(app.ts),app.trig(app.ts),app.label(id))
                app.UIAxes.XLim = ([app.win(1) app.win(end)]);
            catch
            end
        end

        % Callback function
        function Button_9Pushed(app, event)
            %while app.win(end) < app.win_width;%length(app.normic)/app.fs
            %w = app.TimescaleSpinner.Value;
            app.win = app.win + 0.7*(app.win(end)-app.win(1));%0.7*w;
            app.UIAxes.XLim = ([app.win(1) app.win(end)]);
            
            %end
        end

        % Callback function
        function Button_8Pushed(app, event)
            if app.win(1) > 1/app.fs
                w = app.TimescaleSpinner.Value;
                app.win = app.win - 0.7*w;
                app.UIAxes.XLim = ([app.win(1) app.win(end)]);drawnow;
            end
        end

        % Value changing function: JumptoSpinner
        function JumptoSpinnerValueChanging(app, event)
            if app.format == 1
                app.JumptoSpinner.Step = app.TimescaleSpinner.Value*0.8;
                %             else
                %                 app.JumptoSpinner.Step = 1;
            end
            
            changingValue = event.Value;
            try
                if app.format ==1
                    app.win(1) = changingValue;
                    app.win(end) = changingValue+app.TimescaleSpinner.Value;
                    %app.win = app.win + changingValue;
                    app.ts = round(app.win.*app.fs);
                    id = find(app.onset_offset(:,1)>=app.ts(1));
                    %id = id(app.onset_offset(id,2)<=app.ts(end));
                    app.trigID = id(1);
                    %plot_sig(app.UIAxes,app.win,app.normic(app.ts),app.trig(app.ts),app.label(id))
                else
                    app.win(1) = app.onset_offset(changingValue,1)/app.fs - 1;
                    app.win(end) = app.win(1) + app.TimescaleSpinner.Value;
                    app.ts = app.onset_offset(changingValue,1);
                    app.trigID = changingValue;
                    
                end
                app.UIAxes.XLim = ([app.win(1) app.win(end)]);
                app.Slider.Value = app.win(1);
                app.TriggerListListBox.Value = app.label(app.trigID);
                scroll(app.TriggerListListBox,app.label{app.trigID});
                app.audiocs = app.ts(1);
            catch
            end
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            try
                app.win(1) = changingValue;
                app.win(end) = changingValue+app.TimescaleSpinner.Value;
                %app.win = app.win + changingValue;
                app.ts = round(app.win.*app.fs);
                id = find(app.onset_offset(:,1)>=app.ts(1));
                %id = id(app.onset_offset(id,2)<=app.ts(end));
                app.trigID = id(1);
                %plot_sig(app.UIAxes,app.win,app.normic(app.ts),app.trig(app.ts),app.label(id))
                app.UIAxes.XLim = ([app.win(1) app.win(end)]);
            catch
            end
            if app.format == 1
                app.JumptoSpinner.Value = changingValue;
            else
                app.JumptoSpinner.Value = app.trigID;
            end
            app.TriggerListListBox.Value = app.label(app.trigID);
            scroll(app.TriggerListListBox,app.label{app.trigID});
            app.audiocs = app.ts(1);
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Sec'
                    app.JumptoSpinner.Step = app.TimescaleSpinner.Value*0.8;
                    app.format = 1;
                    set(app.JumptoSpinner,'Limits',[0.001 app.win_width],'Value',app.win(1));
                case 'Trigger ID'
                    app.JumptoSpinner.Step = 1;
                    app.format = 2;
                    set(app.JumptoSpinner,'Limits',[1 size(app.onset_offset,1)],'Value',app.trigID);
            end
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            %handles = guidata(src);
            rec=[-1 1];
            %id = find(app.onset_offset(:,1)>=app.ts(1));
            %id = id(app.onset_offset(id,2)<=app.ts(end));
            figure;
            set(gcf,'color',[1 1 1],'position',[802         609        1004         393],'MenuBar', 'none','ToolBar', 'none');
            
            
            %ax = axes('position',[20 20 950 370]);
            if app.trigID == 1
                    ts_trig = round(1:(app.onset_offset(app.trigID,2)+2*app.fs));
            elseif  app.trigID == length(app.label)
                 ts_trig = round((app.ts(1)-2*app.fs):(app.onset_offset(app.trigID,2)+1*app.fs));
            else 
                ts_trig = round((app.ts(1)-2*app.fs):(app.onset_offset(app.trigID,2)+2*app.fs));
            end
            plot_sig(gca,ts_trig/app.fs,app.normic(ts_trig),app.trig(ts_trig),app.label(app.trigID));box off;grid on;
            text(ts_trig(1)/app.fs-0.5,1.1,'***Press Shift to play the audio clip','fontsize',18,'FontName','Optima','color',[193 101 79]/255);
            xlabel('Time (s)');
            ylabel('');
            
            set(gca,'ticklength',[0 0],'fontsize',16,'linewidth',2);
            %playseg = uicontrol('style','togglebutton','value',0,'string','Play');
            set(gcf,'keypressfcn',{@playsegPushed,app.fs,app.normic(ts_trig)});
            try
                [x,y,d]=selectdata('lim_h',rec(1),'lim_u',rec(2),'fillcolor',[0.68 0.92 1],'Identify', 'on','pointer','crosshair');%,'SelectionMode' ,'closest');
            catch
                return;
            end
            if isempty(x)
                return;
            end
            close all;
            ind = ts_trig(1) + x;
            allind = ([ind' app.onset_offset(app.trigID,1):app.onset_offset(app.trigID,2)]);
            count = size(app.onset_offset,1);
            if length(unique(allind)) ~= length(allind)
             app.onset_offset(app.trigID,:)=[ind(1) ind(end)];
             id = app.trigID;
             set(app.labeltxt(id),'Position',[ind(1) 0 0]/app.fs);
            else
                old =  app.onset_offset;
                beforeid = old(:,2)<ind(1);
                bid = find(beforeid);
                new = [old(beforeid,:);[ind(1) ind(end)];old(~beforeid,:)];
                app.onset_offset = new;
                count = size(app.onset_offset,1);
                %app.label = num2str(str2double(app.label(end))+1);
                
                app.label = strsplit(num2str(1:count));
                try
                app.trigID = bid(end)+1;
                catch
                    app.trigID = 1;
                end
                app.TriggerListListBox.Items = app.label;
                app.TriggerListListBox.Value = app.label(app.trigID);
                scroll(app.TriggerListListBox,app.label{app.trigID});
                set(app.Slider,'MajorTicks',app.onset_offset(:,1)/app.fs,'MajorTickLabels',app.label);
                set(app.UIAxes,'nextplot','add');
                
                for i = bid(end)+1:count-1
                    set(app.labeltxt(i),'Position',[app.onset_offset(i,1) 0 0]/app.fs);
                end
                app.labeltxt(count) = text(app.UIAxes,app.onset_offset(count,1)/app.fs,0, app.label{count}, 'FontSize', 12,'color',[1 1 1]);

            set(app.UIAxes,'nextplot','replace');
            end
            trignew = -1*ones(size(app.trig));
            
            for i = 1:count
                trignew(app.onset_offset(i,1):app.onset_offset(i,2)) = 1;
            end
            app.triggermark.YData = trignew;
            app.trig = trignew;
            set(app.figure1,'visible','off');
            set(app.figure1,'visible','on');
            assignin('base','trigger',app.trig);
            assignin('base','trigger_ts',app.onset_offset);
            assignin('base','trigger_labels',app.anno);
            
            %drawnow;
            
            %aid = get(app.UIAxes,'tag');

        end

        % Value changed function: TriggerListListBox
        function TriggerListListBoxValueChanged(app, event)
            value = app.TriggerListListBox.Value;
            app.trigID = find(strcmp(value,app.TriggerListListBox.Items));
            app.win(1) = app.onset_offset(app.trigID,1)/app.fs - 1;
            app.win(end) = app.win(1) + app.TimescaleSpinner.Value;
            app.ts = app.onset_offset(app.trigID,1);
            app.UIAxes.XLim = ([app.win(1) app.win(end)]);
            try
             app.Slider.Value = app.win(1);
            catch
                app.Slider.Value = 0.05;
            end
            if app.format == 1
                if app.trigID == 1
                    app.JumptoSpinner.Value = 0.001;
                else
                app.JumptoSpinner.Value = app.win(1);
                end
            else
                app.JumptoSpinner.Value = app.trigID;
            end
            app.audiocs = app.ts(1);
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            app.onset_offset(app.trigID,:) = [];
            count = size(app.onset_offset,1);
                            app.label(end) = [];
                
                %app.label = strsplit(num2str(1:count));

            if app.trigID == count+1
                app.trigID = count;
            end
            app.TriggerListListBox.Items = app.label;
            app.TriggerListListBox.Value = app.label(app.trigID);
            scroll(app.TriggerListListBox,app.label{app.trigID});
            set(app.Slider,'MajorTicks',app.onset_offset(:,1)/app.fs,'MajorTickLabels',app.label);
            %set(app.UIAxes,'nextplot','add');
            
            for i = app.trigID:count
                set(app.labeltxt(i),'Position',[app.onset_offset(i,1) 0 0]/app.fs);
            end
            delete(app.labeltxt(count+1));
            trignew = -1*ones(size(app.trig));
            for i = 1:count
                trignew(app.onset_offset(i,1):app.onset_offset(i,2)) = 1;
            end
            app.triggermark.YData = trignew;
            app.trig = trignew;
            assignin('base','trigger',app.trig);
            assignin('base','trigger_ts',app.onset_offset);
            assignin('base','trigger_labels',app.anno);
         
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
%             if app.trigID == 1
%                 ts_trig = round(1:(app.onset_offset(app.trigID,2)+0.5*app.fs));
%             elseif  app.trigID == length(app.label)
%                 ts_trig = round((app.ts(1)-0.5*app.fs):(app.onset_offset(app.trigID,2)+0.5*app.fs));
%             else
%                 ts_trig = round((app.ts(1)-0.5*app.fs):(app.onset_offset(app.trigID,2)+0.5*app.fs));
%             end
            ts_trig = round((app.onset_offset(app.trigID,1)):(app.onset_offset(app.trigID,2)));
            soundsc(app.anlg(ts_trig), app.fs);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            if isplaying(app.player)
                pause(app.player);
                app.audiocs = get(app.player,'CurrentSample');
                app.Button_2.Icon = 'audio-volume-medium-panel.png';
                return;
            end
            %
            play(app.player,app.audiocs);
            app.Button_2.Icon = 'pause.png';

            
        end

        % Button pushed function: Button_13
        function Button_13Pushed(app, event)
              input = app.EditField.Value;
              %try
                %labeldiff = str2double(input)-str2double(app.label(app.trigID));
              
                app.anno{app.trigID} = input;
                annolist = get(app.TriggerListListBox,'Items');
                for i = app.trigID
                    %app.label{i} = num2str(str2double(app.label(i)));
                    te = num2str(app.trigID);%get(app.labeltxt(i),'String');
                    te = strcat(te,' ("',app.anno{i},'")');
                    set(app.labeltxt(i),'String',te);
                    annolist{i} = te;
                end
                set(app.TriggerListListBox,'Items',annolist);
              %catch
                  
              %end
              app.EditField.Value = '';
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
             if app.win(end) <= app.win_width-5
                app.UIAxes.XLim = ([app.win(1)+5 app.win(end)+5]);
             else
                 app.UIAxes.XLim = [app.win_width-app.TimescaleSpinner.Value app.win_width];
             end

        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
             if app.win(1) > 5
                app.UIAxes.XLim = ([app.win(1)-5 app.win(end)-5]);
             else
                 app.UIAxes.XLim = ([0.001 app.TimescaleSpinner.Value]);
             end
             
        end

        % Button pushed function: Button_15
        function Button_15Pushed(app, event)
            assignin('base','trigger',app.trig);
            assignin('base','trigger_ts',app.onset_offset);
            assignin('base','trigger_labels',app.anno);
        end

        % Value changed function: ThresholdSpinner
        function ThresholdSpinnerValueChanged(app, event)
            app.thrh = app.ThresholdSpinner.Value;
            
            cut_point1 = diff(([0 app.ups]-[0 app.los])<app.thrh);
            cut_point1(cut_point1~=-1) = 0;
            
            % find cut point end
            cut_point2 = diff(([app.ups 0]-[app.los 0])<app.thrh);
            cut_point2(cut_point2~=1) = 0;
            
            % combine cutpoints
            cutpoints = abs(cut_point1) + cut_point2;
            
            %% Count the number of regions
            ct_fill = ~bwareaopen(~cutpoints, app.min_silence); % remove some intermediate zeros
            [L, numRegions] = bwlabel(ct_fill); % count regions of 1's
            %numRegions
            
            %% Calculate onsets
            ct_fill_0 = (ct_fill~=0);
            ct_fill_d = diff(ct_fill_0);
            start = find([ct_fill_0(1) ct_fill_d]==1)'; % Start index of each group
            finish = find([ct_fill_d -ct_fill_0(end)]==-1)'; % Last index of each group
            %[~,loc]=findpeaks(up,'MinPeakHeight',app.thrh2,'minpeakdistance',4000);
            remove = [];
            n=1;
            for i = 1:length(start)
                if ~isempty(intersect(start(i):finish(i),app.peakloc))
                    remove(n) = i;
                    n = n+1;
                end
            end
            start(remove) = [];
            finish(remove) = [];
           count = length(start);
            
            app.win = [0.001 length(app.anlg)/app.fs];%full window
            
            app.win_width=app.win(end);
            app.audiocs = 1;
            app.audiocs_b = 1;
            app.onset_offset = zeros(count,2);
            base = -1*ones(size(app.anlg));
            app.trig = base;
            app.label = strsplit(num2str(1:count));
            for i = 1:count
                app.onset_offset(i,:) = [ start(i) finish(i)] ;
                app.trig(start(i):finish(i)) = 1;
            end
            app.triggermark.YData = app.trig;
            %app.triggermark = area(app.UIAxes,app.t,app.trig,-1,'facealpha',0.3,'linestyle','none','facecolor',[0 0.45 0.74]);
            app.audioline = line(app.UIAxes,[app.audiocs app.audiocs],[-1 1],'color',[0.93 0.69 0.13],'linewidth',2);
            set(app.player,'TimerFcn',{@timerline,app.audioline}, 'TimerPeriod', 0.1);

            set(app.UIAxes,'nextplot','add');
            for i = 1:length(app.onset_offset)
                try
                    delete(app.labeltxt(i));
                catch
                end
                %plot(app.onset_offset{i}(1):app.onset_offset{i}(2),zeros(length(anlg(app.onset_offset{i}(1):app.onset_offset{i}(2))),1), 'LineWidth', 50, 'Color', 'red')
                app.labeltxt(i) = text(app.UIAxes,app.onset_offset(i,1)/app.fs,0, num2str(i), 'FontSize', 12,'color',[1 1 1]);
            end
            set(app.UIAxes,'nextplot','replace');
            set(app.TriggerListListBox,'Items',app.label);
            %app.UIAxes.XLabel.String=('Time (s)');
            %app.UIAxes.YLabel.String='';
            app.trigID = 1;
            app.anno = cell(1,count);
            %app.ts = [1 length(app.trig)];
            set(app.TimescaleSpinner,'Limits',[0.05 app.win(end)],'Value',app.win(end),'Step',10);
            set(app.Slider,'Limits',[0.001 app.win(end)],'Value',app.win(1),'MajorTicks',app.onset_offset(:,1)/app.fs,'MajorTickLabels',app.label);
            %set(app.ThresholdSpinner,'Value',0.2,'Step',0.05);
            set(app.TriggerListListBox,'Items',strsplit(num2str(1:length(app.onset_offset))));
            set(app.JumptoSpinner,'Limits',[0.001 app.win(end)],'Value',0.001,'Step',5);
        end

        % Value changed function: RejectSpinner
        function RejectSpinnerValueChanged(app, event)
            app.thrh2 = app.RejectSpinner.Value;

            
            cut_point1 = diff(([0 app.ups]-[0 app.los])<app.thrh);
            cut_point1(cut_point1~=-1) = 0;
            
            % find cut point end
            cut_point2 = diff(([app.ups 0]-[app.los 0])<app.thrh);
            cut_point2(cut_point2~=1) = 0;
            
            % combine cutpoints
            cutpoints = abs(cut_point1) + cut_point2;
            
            %% Count the number of regions
            ct_fill = ~bwareaopen(~cutpoints, app.min_silence); % remove some intermediate zeros
            [L, numRegions] = bwlabel(ct_fill); % count regions of 1's
            %numRegions
            
            %% Calculate onsets
            ct_fill_0 = (ct_fill~=0);
            ct_fill_d = diff(ct_fill_0);
            start = find([ct_fill_0(1) ct_fill_d]==1)'; % Start index of each group
            finish = find([ct_fill_d -ct_fill_0(end)]==-1)'; % Last index of each group
            try
            [~,app.peakloc]=findpeaks(app.ups,'MinPeakHeight',app.thrh2,'minpeakdistance',2000);
            catch
                app.peakloc = [];
            end
            remove = [];
            n=1;
            for i = 1:length(start)
                if ~isempty(intersect(start(i):finish(i),app.peakloc))
                    remove(n) = i;
                    n = n+1;
                end
            end
            start(remove) = [];
            finish(remove) = [];
           count = length(start);
            
            app.win = [0.001 length(app.anlg)/app.fs];%full window
            
            app.win_width=app.win(end);
            app.audiocs = 1;
            app.audiocs_b = 1;
            app.onset_offset = zeros(count,2);
            base = -1*ones(size(app.anlg));
            app.trig = base;
            app.label = strsplit(num2str(1:count));
            for i = 1:count
                app.onset_offset(i,:) = [ start(i) finish(i)] ;
                app.trig(start(i):finish(i)) = 1;
            end
            app.triggermark.YData = app.trig;
            %app.triggermark = area(app.UIAxes,app.t,app.trig,-1,'facealpha',0.3,'linestyle','none','facecolor',[0 0.45 0.74]);
            app.audioline = line(app.UIAxes,[app.audiocs app.audiocs],[-1 1],'color',[0.93 0.69 0.13],'linewidth',2);
            set(app.player,'TimerFcn',{@timerline,app.audioline}, 'TimerPeriod', 0.1);
            set(app.UIAxes,'nextplot','add');
            for i = 1:length(app.onset_offset)
                try
                    delete(app.labeltxt(i));
                catch
                end
                %plot(app.onset_offset{i}(1):app.onset_offset{i}(2),zeros(length(anlg(app.onset_offset{i}(1):app.onset_offset{i}(2))),1), 'LineWidth', 50, 'Color', 'red')
                app.labeltxt(i) = text(app.UIAxes,app.onset_offset(i,1)/app.fs,0, num2str(i), 'FontSize', 12,'color',[1 1 1]);
            end
            set(app.UIAxes,'nextplot','replace');
            set(app.TriggerListListBox,'Items',app.label);
            %app.UIAxes.XLabel.String=('Time (s)');
            %app.UIAxes.YLabel.String='';
            app.trigID = 1;
            app.anno = cell(1,count);
            %app.ts = [1 length(app.trig)];
            set(app.TimescaleSpinner,'Limits',[0.05 app.win(end)],'Value',app.win(end),'Step',10);
            set(app.Slider,'Limits',[0.001 app.win(end)],'Value',app.win(1),'MajorTicks',app.onset_offset(:,1)/app.fs,'MajorTickLabels',app.label);
            %set(app.ThresholdSpinner,'Value',0.2,'Step',0.05);
            set(app.TriggerListListBox,'Items',strsplit(num2str(1:length(app.onset_offset))));
            set(app.JumptoSpinner,'Limits',[0.001 app.win(end)],'Value',0.001,'Step',5);
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            value = app.CheckBox.Value;
            if value == 1
                app.RejectSpinner.Enable = 'on';
                app.thrh2 = app.RejectSpinner.Value;
                
                
                cut_point1 = diff(([0 app.ups]-[0 app.los])<app.thrh);
                cut_point1(cut_point1~=-1) = 0;
                
                % find cut point end
                cut_point2 = diff(([app.ups 0]-[app.los 0])<app.thrh);
                cut_point2(cut_point2~=1) = 0;
                
                % combine cutpoints
                cutpoints = abs(cut_point1) + cut_point2;
                
                %% Count the number of regions
                ct_fill = ~bwareaopen(~cutpoints, app.min_silence); % remove some intermediate zeros
                [L, numRegions] = bwlabel(ct_fill); % count regions of 1's
                %numRegions
                
                %% Calculate onsets
                ct_fill_0 = (ct_fill~=0);
                ct_fill_d = diff(ct_fill_0);
                start = find([ct_fill_0(1) ct_fill_d]==1)'; % Start index of each group
                finish = find([ct_fill_d -ct_fill_0(end)]==-1)'; % Last index of each group
                try
                    [~,app.peakloc]=findpeaks(app.ups,'MinPeakHeight',app.thrh2,'minpeakdistance',1000);
                catch
                    app.peakloc = [];
                end
                remove = [];
                n=1;
                for i = 1:length(start)
                    if ~isempty(intersect(start(i):finish(i),app.peakloc))
                        remove(n) = i;
                        n = n+1;
                    end
                end
                start(remove) = [];
                finish(remove) = [];
                count = length(start);
                
                app.win = [0.001 length(app.anlg)/app.fs];%full window
                
                app.win_width=app.win(end);
                app.audiocs = 1;
                app.audiocs_b = 1;
                app.onset_offset = zeros(count,2);
                base = -1*ones(size(app.anlg));
                app.trig = base;
                app.label = strsplit(num2str(1:count));
                for i = 1:count
                    app.onset_offset(i,:) = [ start(i) finish(i)] ;
                    app.trig(start(i):finish(i)) = 1;
                end
                app.triggermark.YData = app.trig;
                %app.triggermark = area(app.UIAxes,app.t,app.trig,-1,'facealpha',0.3,'linestyle','none','facecolor',[0 0.45 0.74]);
                app.audioline = line(app.UIAxes,[app.audiocs app.audiocs],[-1 1],'color',[0.93 0.69 0.13],'linewidth',2);
                set(app.player,'TimerFcn',{@timerline,app.audioline}, 'TimerPeriod', 0.1);
                set(app.UIAxes,'nextplot','add');
                for i = 1:length(app.onset_offset)
                     try
                        delete(app.labeltxt(i));
                        app.labeltxt(i) = text(app.UIAxes,app.onset_offset(i,1)/app.fs,0, num2str(i), 'FontSize', 12,'color',[1 1 1]);
                    catch
                        app.labeltxt(i) = text(app.UIAxes,app.onset_offset(i,1)/app.fs,0, num2str(i), 'FontSize', 12,'color',[1 1 1]);
                     end
                end
                set(app.UIAxes,'nextplot','replace');
                %app.UIAxes.XLabel.String=('Time (s)');
                %app.UIAxes.YLabel.String='';
                app.trigID = 1;
                app.anno = cell(1,count);
                %app.ts = [1 length(app.trig)];
                set(app.TimescaleSpinner,'Limits',[0.05 app.win(end)],'Value',app.win(end),'Step',10);
                set(app.Slider,'Limits',[0.001 app.win(end)],'Value',app.win(1),'MajorTicks',app.onset_offset(:,1)/app.fs,'MajorTickLabels',app.label);
                %set(app.ThresholdSpinner,'Value',0.2,'Step',0.05);
                set(app.TriggerListListBox,'Items',strsplit(num2str(1:length(app.onset_offset))));
                set(app.JumptoSpinner,'Limits',[0.001 app.win(end)],'Value',0.001,'Step',5);
            else
                app.RejectSpinner.Enable = 'off';
                cut_point1 = diff(([0 app.ups]-[0 app.los])<app.thrh);
                cut_point1(cut_point1~=-1) = 0;
                
                % find cut point end
                cut_point2 = diff(([app.ups 0]-[app.los 0])<app.thrh);
                cut_point2(cut_point2~=1) = 0;
                
                % combine cutpoints
                cutpoints = abs(cut_point1) + cut_point2;
                
                %% Count the number of regions
                ct_fill = ~bwareaopen(~cutpoints, app.min_silence); % remove some intermediate zeros
                [L, numRegions] = bwlabel(ct_fill); % count regions of 1's
                %numRegions
                
                %% Calculate onsets
                ct_fill_0 = (ct_fill~=0);
                ct_fill_d = diff(ct_fill_0);
                start = find([ct_fill_0(1) ct_fill_d]==1)'; % Start index of each group
                finish = find([ct_fill_d -ct_fill_0(end)]==-1)'; % Last index of each group
           
                count = length(start);
                
                app.win = [0.001 length(app.anlg)/app.fs];%full window
                
                app.win_width=app.win(end);
                app.audiocs = 1;
                app.audiocs_b = 1;
                app.onset_offset = zeros(count,2);
                base = -1*ones(size(app.anlg));
                app.trig = base;
                app.label = strsplit(num2str(1:count));
                for i = 1:count
                    app.onset_offset(i,:) = [ start(i) finish(i)] ;
                    app.trig(start(i):finish(i)) = 1;
                end
                app.triggermark.YData = app.trig;
                %app.triggermark = area(app.UIAxes,app.t,app.trig,-1,'facealpha',0.3,'linestyle','none','facecolor',[0 0.45 0.74]);
                app.audioline = line(app.UIAxes,[app.audiocs app.audiocs],[-1 1],'color',[0.93 0.69 0.13],'linewidth',2);
                set(app.player,'TimerFcn',{@timerline,app.audioline}, 'TimerPeriod', 0.1);
                set(app.UIAxes,'nextplot','add');
                for i = 1:length(app.onset_offset)
                    try
                        delete(app.labeltxt(i));
                        app.labeltxt(i) = text(app.UIAxes,app.onset_offset(i,1)/app.fs,0, num2str(i), 'FontSize', 12,'color',[1 1 1]);
                    catch
                        app.labeltxt(i) = text(app.UIAxes,app.onset_offset(i,1)/app.fs,0, num2str(i), 'FontSize', 12,'color',[1 1 1]);
                    end
                    %plot(app.onset_offset{i}(1):app.onset_offset{i}(2),zeros(length(anlg(app.onset_offset{i}(1):app.onset_offset{i}(2))),1), 'LineWidth', 50, 'Color', 'red')
                    %app.labeltxt(i) = text(app.UIAxes,app.onset_offset(i,1)/app.fs,0, num2str(i), 'FontSize', 12,'color',[1 1 1]);
                end
                set(app.UIAxes,'nextplot','replace');
                %app.UIAxes.XLabel.String=('Time (s)');
                %app.UIAxes.YLabel.String='';
                app.trigID = 1;
                app.anno = cell(1,count);
                %app.ts = [1 length(app.trig)];
                set(app.TimescaleSpinner,'Limits',[0.05 app.win(end)],'Value',app.win(end),'Step',10);
                set(app.Slider,'Limits',[0.001 app.win(end)],'Value',app.win(1),'MajorTicks',app.onset_offset(:,1)/app.fs,'MajorTickLabels',app.label);
                %set(app.ThresholdSpinner,'Value',0.2,'Step',0.05);
                set(app.TriggerListListBox,'Items',strsplit(num2str(1:length(app.onset_offset))));
                set(app.JumptoSpinner,'Limits',[0.001 app.win(end)],'Value',0.001,'Step',5);
            end
        end

        % Callback function
        function Button_17Pushed(app, event)

        end

        % Value changed function: Button_18
        function Button_18ValueChanged(app, event)
            value = app.Button_18.Value;
            if value == 1
                for i = 1:length(app.stimplot)
                set(app.stimplot(i),'visible','on')
                end
                for i = 1:size(app.stim,1)
                    set(app.stimtxt(i),'visible','on');
                end
            else
                for i = 1:length(app.stimplot)
                set(app.stimplot(i),'visible','off')
                end
                for i = 1:size(app.stim,1)
                    set(app.stimtxt(i),'visible','off');
                end
            end
        end

        % Button pushed function: Button_16
        function Button_16Pushed(app, event)
            [filename,pathname] = uigetfile({'*.mat','Data format (*.mat)'},...
                'MultiSelect', 'off');
            fname = fullfile(pathname,filename);
            if isempty(fname) || isempty(filename)
                return;
            else
                
                try
                load(fname);
                catch
                    return;
                end
            end
            app.trig = trigger;
            app.triggermark.YData = app.trig;
            count = length(trigger_ts);
            app.label = strsplit(num2str(1:count));
            app.anno = trigger_labels;
            app.onset_offset = trigger_ts;
            %set(app.UIAxes,'nextplot','add');
            delete(app.labeltxt)
            for i = 1:length(app.onset_offset)
                app.labeltxt(i) = text(app.UIAxes,app.onset_offset(i,1)/app.fs,0, num2str(i), 'FontSize', 12,'color',[1 1 1]);
                
            end
            %set(app.UIAxes,'nextplot','replace');
            set(app.TriggerListListBox,'Items',app.label)
            
            annolist = get(app.TriggerListListBox,'Items');
            for i = 1:count
                %app.label{i} = num2str(str2double(app.label(i)));
                te = app.label{i};%get(app.labeltxt(i),'String');
                    if ~isempty(app.anno{i})
                        %try
                            te = strcat(te,' ("',app.anno{i},'")');
                            set(app.labeltxt(i),'String',te);
                            annolist{i} = te;
                        %catch
                        %end
                    end
                end
                set(app.TriggerListListBox,'Items',annolist);


%             count = length(start);
%             app.format = 1; %in seconds
%             app.win = [0.001 length(anlg)/app.fs];%full window
%             app.t = (1 : length(anlg))/app.fs;
%             app.win_width=app.win(end);
%             app.audiocs = 1;
%             app.audiocs_b = 1;
%             app.onset_offset = zeros(count,2);
%             base = -1*ones(size(anlg));
%             app.trig = base;
%             app.label = strsplit(num2str(1:count));
%             for i = 1:count
%                 app.onset_offset(i,:) = [ start(i) finish(i)] ;
%                 app.trig(start(i):finish(i)) = 1;
%             end
%             
%             app.wave = plot(app.UIAxes,app.t,app.normic,'linewidth',1,'color',[0.35 0.35 0.35]);
%             set(app.UIAxes,'linewidth',1,'fontsize',12,'ticklength',[0.05 0.05],'ytick',[])%'color',[0.94 0.94 0.94]);
%             app.UIAxes.XLim=([0 app.win(end)]);
%             app.UIAxes.YLim=([-1 1]);
%             set(app.UIAxes,'nextplot','add');
%             app.triggermark = area(app.UIAxes,app.t,app.trig,-1,'facealpha',0.3,'linestyle','none','facecolor',[0 0.45 0.74]);
%             app.audioline = line(app.UIAxes,[app.audiocs app.audiocs],[-1 1],'color',[0.93 0.69 0.13],'linewidth',2);
%             set(app.player,'TimerFcn',{@timerline,app.audioline}, 'TimerPeriod', 0.1);
%             
%             for i = 1:length(app.onset_offset)
%                 %plot(app.onset_offset{i}(1):app.onset_offset{i}(2),zeros(length(anlg(app.onset_offset{i}(1):app.onset_offset{i}(2))),1), 'LineWidth', 50, 'Color', 'red')
%                 app.labeltxt(i) = text(app.UIAxes,app.onset_offset(i,1)/app.fs,0, num2str(i), 'FontSize', 12,'color',[1 1 1]);
%             end
%             cm=[94 168 101;219 194 134;193 101 79]./255;
%             for i = 1:size(app.stim,2)
%                 if i == size(app.stim,2)
%                     app.stimplot(i) = stem(app.UIAxes,app.stim(:,i), ones(length(app.stim(:,1)),1), '.', 'linewidth', 1,'color',cm(i,:));
%                 else
%                     app.stimplot(i) = plot(app.UIAxes,app.stim(:,i), ones(length(app.stim(:,1)),1), '.','color',cm(i,:),'markersize',15);
%                 end
%             end
%             for i = 1:size(app.stim,1)
%                 %plot(app.onset_offset{i}(1):app.onset_offset{i}(2),zeros(length(anlg(app.onset_offset{i}(1):app.onset_offset{i}(2))),1), 'LineWidth', 50, 'Color', 'red')
%                 app.stimtxt(i) = text(app.UIAxes,app.stim(i,end)-0.01,0.9, num2str(i), 'FontSize', 12,'color',[193 101 79]/255);
%             end
%             set(app.UIAxes,'nextplot','replace');
%             app.UIAxes.XLabel.String=('Time (s)');
%             app.UIAxes.YLabel.String='';
%             app.trigID = 1;
%             app.anno = cell(1,count);
%             app.ts = [1 length(app.trig)];
%             set(app.TimescaleSpinner,'Limits',[0.05 app.win(end)],'Value',app.win(end),'Step',10);
%             set(app.Slider,'Limits',[0.001 app.win(end)],'Value',app.win(1),'MajorTicks',app.onset_offset(:,1)/app.fs,'MajorTickLabels',app.label);
%             set(app.ThresholdSpinner,'Value',0.2,'Step',0.05);
%             set(app.TriggerListListBox,'Items',strsplit(num2str(1:length(app.onset_offset))));
%             set(app.JumptoSpinner,'Limits',[0.001 app.win(end)],'Value',0.001,'Step',5);
        end

        % Button pushed function: Button_19
        function Button_19Pushed(app, event)
            assignin('base','trigger',app.trig);
            assignin('base','trigger_ts',app.onset_offset);
            assignin('base','trigger_labels',app.anno);
            trigger = app.trig;
            trigger_ts = app.onset_offset;
            trigger_labels = app.anno;
            uisave({'trigger','trigger_ts','trigger_labels'},'saved_audioTrigger');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure;
            app.figure1.Name = 'audioMic';
            app.AudiowavePanel = uipanel(app.figure1);
            app.AudiowavePanel.Title = 'Audio wave';
            app.UIAxes = uiaxes(app.AudiowavePanel);
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Y')
            app.Panel_2 = uipanel(app.figure1);
            app.TriggerListListBoxLabel = uilabel(app.Panel_2);
            app.TriggerListListBoxLabel.Text = 'Trigger List';
            app.TriggerListListBox = uilistbox(app.Panel_2);
            app.TriggerListListBox.ValueChangedFcn = createCallbackFcn(app, @TriggerListListBoxValueChanged, true);
            app.Button_11 = uibutton(app.Panel_2, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Icon = 'play.png';
            app.Button_11.Text = '';
            app.Button_12 = uibutton(app.Panel_2, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Icon = '2001870.png';
            app.Button_12.Text = '';
            app.Button_13 = uibutton(app.Panel_2, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @Button_13Pushed, true);
            app.Button_13.Icon = 'feedback_note-512.png';
            app.Button_13.Text = '';
            app.EditField = uieditfield(app.Panel_2, 'text');
            app.InfoPanel = uipanel(app.figure1);
            app.InfoPanel.Title = 'Info';
            app.Label = uilabel(app.InfoPanel);
            app.Label.Text = '';
            app.Info = uitextarea(app.InfoPanel);
            app.Info.Editable = 'off';
            app.Panel = uipanel(app.figure1);
            app.Button_2 = uibutton(app.Panel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'audio-volume-medium-panel.png';
            app.Button_2.Text = '';
            app.ButtonGroup = uibuttongroup(app.Panel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.SecButton = uiradiobutton(app.ButtonGroup);
            app.SecButton.Text = 'Sec';
            app.SecButton.Value = true;
            app.TriggerIDButton = uiradiobutton(app.ButtonGroup);
            app.TriggerIDButton.Text = 'Trigger ID';
            app.TimescaleSpinnerLabel = uilabel(app.Panel);
            app.TimescaleSpinnerLabel.Text = 'Time scale';
            app.TimescaleSpinner = uispinner(app.Panel);
            app.TimescaleSpinner.ValueChangingFcn = createCallbackFcn(app, @TimescaleSpinnerValueChanging, true);
            app.ThresholdSpinnerLabel = uilabel(app.Panel);
            app.ThresholdSpinnerLabel.Text = 'Threshold';
            app.ThresholdSpinner = uispinner(app.Panel);
            app.ThresholdSpinner.Step = 0.1;
            app.ThresholdSpinner.Limits = [0 1];
            app.ThresholdSpinner.ValueChangedFcn = createCallbackFcn(app, @ThresholdSpinnerValueChanged, true);
            app.ThresholdSpinner.Value = 0.2;
            app.Button_6 = uibutton(app.Panel, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Icon = 'md_5a9bfb1bc03dd copy.png';
            app.Button_6.Enable = 'off';
            app.Button_6.Text = '';
            app.Button_7 = uibutton(app.Panel, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Icon = 'md_5a9bfb1bc03dd.png';
            app.Button_7.Enable = 'off';
            app.Button_7.Text = '';
            app.Button_10 = uibutton(app.Panel, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Icon = 'touch-icon.png';
            app.Button_10.Text = '';
            app.JumptoSpinnerLabel = uilabel(app.Panel);
            app.JumptoSpinnerLabel.Text = 'Jump to ';
            app.JumptoSpinner = uispinner(app.Panel);
            app.JumptoSpinner.ValueChangingFcn = createCallbackFcn(app, @JumptoSpinnerValueChanging, true);
            app.JumptoSpinner.ValueDisplayFormat = '%.0f';
            app.Button_15 = uibutton(app.Panel, 'push');
            app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @Button_15Pushed, true);
            app.Button_15.Icon = 'export.png';
            app.Button_15.Text = '';
            app.Button_16 = uibutton(app.Panel, 'push');
            app.Button_16.ButtonPushedFcn = createCallbackFcn(app, @Button_16Pushed, true);
            app.Button_16.Icon = 'open2.png';
            app.Button_16.Text = '';
            app.Button_14 = uibutton(app.Panel, 'push');
            app.Button_14.Icon = 'cut-paper.png';
            app.Button_14.Enable = 'off';
            app.Button_14.Text = '';
            app.RejectSpinnerLabel = uilabel(app.Panel);
            app.RejectSpinnerLabel.Text = 'Reject';
            app.RejectSpinner = uispinner(app.Panel);
            app.RejectSpinner.Step = 0.1;
            app.RejectSpinner.Limits = [0 1];
            app.RejectSpinner.ValueChangedFcn = createCallbackFcn(app, @RejectSpinnerValueChanged, true);
            app.RejectSpinner.Value = 0.8;
            app.CheckBox = uicheckbox(app.Panel);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '';
            app.CheckBox.Value = true;
            app.Button_18 = uibutton(app.Panel, 'state');
            app.Button_18.ValueChangedFcn = createCallbackFcn(app, @Button_18ValueChanged, true);
            app.Button_18.Icon = 'info.png';
            app.Button_18.Text = '';
            app.Button_18.Value = true;
            app.Button_19 = uibutton(app.Panel, 'push');
            app.Button_19.ButtonPushedFcn = createCallbackFcn(app, @Button_19Pushed, true);
            app.Button_19.Icon = 'save.png';
            app.Button_19.Text = '';
            app.Slider = uislider(app.figure1);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.MinorTicks = [];
            app.TriggerIDLabel = uilabel(app.figure1);
            app.TriggerIDLabel.Text = 'Trigger ID';
        end
    end

    methods (Access = public)

        % Construct app
        function app = audioMic_App(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)audioMic_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end