classdef plot_window_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                 matlab.ui.Figure
        axes4                   matlab.ui.control.UIAxes
        uipanel4                matlab.ui.container.Panel
        savebtn                 matlab.ui.control.Button
        export                  matlab.ui.control.Button
        inspectBtn              matlab.ui.control.Button
        pushbutton2             matlab.ui.control.Button
        pushbutton1             matlab.ui.control.Button
        loadBtn                 matlab.ui.control.Button
        Button_2                matlab.ui.control.Button
        openBtn                 matlab.ui.control.Button
        addBtn                  matlab.ui.control.Button
        checkbox2               matlab.ui.control.CheckBox
        Panel_2                 matlab.ui.container.Panel
        logo                    matlab.ui.control.UIAxes
        Panel_3                 matlab.ui.container.Panel
        slider1                 matlab.ui.control.Slider
        text1                   matlab.ui.control.Label
        slider2                 matlab.ui.control.Slider
        text2                   matlab.ui.control.Label
        slider3                 matlab.ui.control.Slider
        text3                   matlab.ui.control.Label
        Switch                  matlab.ui.control.Switch
        showL                   matlab.ui.control.CheckBox
        showR                   matlab.ui.control.CheckBox
        showMap                 matlab.ui.control.CheckBox
        Panel_4                 matlab.ui.container.Panel
        plottab                 matlab.ui.container.TabGroup
        plot1                   matlab.ui.container.Tab
        axes1                   matlab.ui.control.UIAxes
        pushbutton9             matlab.ui.control.Button
        pushbutton10            matlab.ui.control.Button
        Button                  matlab.ui.control.Button
        tfpanel                 matlab.ui.container.Panel
        ConditionDropDownLabel  matlab.ui.control.Label
        ConditionDropDown       matlab.ui.control.DropDown
        tfaxes                  matlab.ui.control.UIAxes
        Button_4                matlab.ui.control.Button
        Button_5                matlab.ui.control.Button
        Button_6                matlab.ui.control.Button
        Panel_6                 matlab.ui.container.Panel
        axes3                   matlab.ui.control.UIAxes
        Button_3                matlab.ui.control.Button
        panel2                  matlab.ui.container.Panel
        TextArea                matlab.ui.control.TextArea
        TabGroup                matlab.ui.container.TabGroup
        ForfutureuseTab         matlab.ui.container.Tab
        Tab                     matlab.ui.container.Tab
        Panel_7                 matlab.ui.container.Panel
        Panel_10                matlab.ui.container.Panel
        Panel_15                matlab.ui.container.Panel
        Panel_16                matlab.ui.container.Panel
        Panel_17                matlab.ui.container.Panel
        Panel_18                matlab.ui.container.Panel
        TabGroup2               matlab.ui.container.TabGroup
        PlotsTab                matlab.ui.container.Tab
        text9                   matlab.ui.control.Label
        text4                   matlab.ui.control.Label
        popupmenu1              matlab.ui.control.DropDown
        text4_2                 matlab.ui.control.Label
        popupmenu1_2            matlab.ui.control.DropDown
        listbox1                matlab.ui.control.ListBox
        ShowStatsCheckBox       matlab.ui.control.CheckBox
        ConditionsTab           matlab.ui.container.Tab
    end

    
    properties (Access = public)
        
        signal_all
        sparam
        labels
        D
        window
        plot_cond
        page
        yl
        bch
        t
        bc_type
        cpath
        sig_chan
        signal_backup
        nanid
        showslice
        elecMatrix
        elecLabels
        elecRgb
        eleinuse
        V
        m
        order
        sel_cond
        output
        overlay
        elecoor
        slice
        labeltext
        currele
        curr_elec
        cc
        legendp
        slice2d_axes
        group
        groupNames
        cbh
        alpha
        rh
        lh
        fpos
        inipos
        buttonpos
        axes1pos
        logopos
        model
        isrender
        head_center
        hmsg
        addsig
        axes1a
        axes1b
        plot2
        plot3
        sigoverlay
        task
        sname
        nchannels
        tf
        tft
        tff
        tfplot
        sel_tf
        tfscale
        sig_pval
        sig_ts
        phandle
        plot_p
        f
        e
        ei
        actMap
        %listbox1
    end
    
    methods (Access = private)
        function CloseMenuItem_Callback(app, hObject, eventdata, handles)
            % hObject    handle to CloseMenuItem (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            selection = questdlg(['Close ' get(app.figure1,'Name') '?'],...
                ['Close ' get(app.figure1,'Name') '...'],...
                'Yes','No','Yes');
            if strcmp(selection,'No')
                return;
            end
            clc;
            delete(app.figure1)
        end
        
        function OpenMenuItem_Callback(app, hObject, eventdata, handles)
            % hObject    handle to OpenMenuItem (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            file = uigetfile('*.fig');
            if ~isequal(file, 0)
                open(file);
            end
        end
        
        function PrintMenuItem_Callback(app, hObject, eventdata, handles)
            % hObject    handle to PrintMenuItem (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            printdlg(app.figure1)
        end
        
        function checkBoxCallback(app, hObject, eventdata, checkBoxId, handles)
            hObject_b = hObject;
            handles = guidata(gcf);
            app.sel_cond(checkBoxId) = get(hObject_b,'Value');
            axes(app.axes1);
            plot_browser(app.signal_all(app.sel_cond), app.sparam,app.labels,app.D,...
                app.window,app.plot_cond(app.sel_cond), app.order(app.page),...
                app.yl,app.bch,app.t,1,app.cc(app.sel_cond,:));
            % guidata(app);
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function plot_window_OpeningFcn(app, varargin)
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to plot_window (see VARARGIN)
            
            %plot_window(signal_all, sparam,labels,D,window,plot_cond, page, yl, bch)
            %LBCN_viewer_getDefault

            app.figure1.Name = 'Welcome to LBCN';
            logop = imread('logos2.png');
            imshow(logop,'Parent',app.logo);
            app.fpos = [644          81        1465         900];
            app.logopos = get(app.Panel_2,'Position');
            app.buttonpos = get(app.uipanel4,'Position');
            app.axes1pos = get(app.axes1,'Position');
            set(app.figure1,'position',[140+644 481 590 477],'resize','off');
            set(app.uipanel4,'position',[5 400 578 71]);
            app.inipos = get(app.Panel_4,'position');
            set(app.Panel_4,'position',[5 8 app.inipos(3) app.inipos(4)]);
            app.tf=[];
            if  isempty(varargin)%open the GUI only
                homeimg = imread('home1.png');
                imshow(homeimg,'parent',app.axes1);
                wmsg = sprintf('%s\n%s\n%s\n%s\n%s\n%s\n%s\n%s','WELCOME TO LBCN,','','To start new processing: OPEN edf',...
                    '','To view past results: LOAD','','');
                app.hmsg = uilabel(app.figure1,'Text',wmsg,'Position',[225 85 250 200]);
                app.hmsg.FontName = 'Helvetica-Narrow';
                app.hmsg.FontSize = 14;
                app.hmsg.FontColor = [1 1 1];
                app.hmsg.HorizontalAlignment = 'center';
                return;
            else
                app.signal_all= varargin{1};
                app.sparam = varargin{2};
                app.labels = varargin{3};
                app.D = varargin{4} ;
                app.window = varargin{5};
                app.plot_cond = varargin{6} ;
                app.page = varargin{7};
                app.cpath = varargin{8};
                app.bch = varargin{9};
                app.t = varargin{10};
                app.yl = [-0.5 1.5];
                %app.yl = [-5 15];
                setappdata(app.figure1,'signal_other',varargin{11});
                app.bc_type = varargin{12};
                %load home pic
                app.sigoverlay=1;
                homeimg = imread('home2.png');
                imshow(homeimg,'parent',app.axes1);
                wmsg = sprintf('%s\n%s\n%s\n%s\n%s','Initializing...','','Please wait');
                app.hmsg = uilabel(app.figure1,'Text',wmsg,'Position',[300 95 250 200]);
                app.hmsg.FontName = 'Helvetica-Narrow';
                app.hmsg.FontSize = 14;
                app.hmsg.FontColor = [0 0 0];
                app.hmsg.HorizontalAlignment = 'center';
                pause(0.1);
                try
                    if length(varargin) == 14 && ~isempty(varargin{14})
                        information = varargin{14};
                        if isfield(information,'project_name')
                            app.task = information.project_name;
                        end
                        if isfield(information,'sbj_name')
                            app.sname = information.sbj_name;
                        end

                        if isfield(information,'tf') && ~isempty(information.tf)
                            try
                                TF = load(information.tf);
                                app.tf = getfield(TF,string(fieldnames(TF)));
                            catch
                                app.tf = [];
                            end
                        else
                            try
                            information.tf = find_file(fullfile(app.cpath),'Epoched_tf*.mat',[]);

                            TF = load(information.tf);
                            app.tf = getfield(TF,string(fieldnames(TF)));
                            if length(app.tf) ==1  && iscell(app.tf)
                                app.tf=app.tf{1};
                            end
                            catch
                                app.tf = [];
                            end
                            
                        end
                        if isfield(information,'f') && ~isempty(information.f)
                            app.f = information.f;
                        else
                            app.f = 180;
                        end
                    else
                         [app.task,app.sname] = get_info(app.cpath);
                        out = timer_getinfo(app.task,app.sname);
                        app.task = out.task;
                        app.sname = out.sname;
                        
                    end
                    if isempty(app.task) || isempty(app.sname)
                        [app.task,app.sname] = get_info(app.cpath);
                        out = timer_getinfo(app.task,app.sname);
                        app.task = out.task;
                        app.sname = out.sname;
                    end
                catch
                    [app.task,app.sname] = get_info(app.cpath);
                    out = timer_getinfo(app.task,app.sname);
                    app.task = out.task;
                    app.sname = out.sname;
                end
                information.project_name = app.task;
                information.sbj_name = app.sname;
                app.figure1.Name = strcat(app.sname,' ',app.task);
                app.plot1.Title = app.task;
            end
            %enable components
        %% Run permutation test
        %ending = length(app.signal_all{1}{1})-50;

        if length(varargin) == 14 && ~isempty(varargin{14})
            if isfield(information,'sigChan')
                app.sig_chan = information.sigChan;
                app.sig_ts = information.sigTs;
                app.sig_pval = information.sigPval;
            else
              information.sigChan = [];
              information.sigTs = [];
              information.sigPval = [];
            end
        else
            information.sigChan = [];
            information.sigTs = [];
            information.sigPval = [];
        end
        if isempty(information.sigChan)
            start = find(app.t==0);
            %start = round(length(app.signal_all{1}{1})/6);
            sig_channel = cell(length(app.plot_cond),1);
            %% A silly workaround to put the figure window in front
            app.figure1.Visible = 'off';
            app.figure1.Visible = 'on';
            %%
            app.hmsg.Text = sprintf('..Running statistics for cond 1');pause(0.2);
            for k=1:length(app.plot_cond)
                app.hmsg.Text = sprintf('Running statistics for cond %d ...',k);
                pause(0.1);
                %[sig_channel{k}, app.sig_pval(:,:,k), sig_t(:,:,k)] = permutation_test(app.signal_all{k},start);
                [sig_channel{app.plot_cond(k)}, app.sig_pval(:,:,app.plot_cond(k)), sig_t(:,:,app.plot_cond(k))] = permutation_test(app.signal_all{k},start);
            end
            app.hmsg.Text = sprintf('Loading..\n');
            app.sig_chan = sig_channel;
            app.sig_ts = sig_t;
            information.sigChan = app.sig_chan;
            information.sigTs = app.sig_ts;
            information.sigPval = app.sig_pval;
        end
        %%
        
        app.signal_backup=app.signal_all;
        if length(varargin) >= 13 && ~isempty(varargin{13})%do we have nan indicies?
            if length(varargin{13})==1
                app.nanid = varargin{13}{1};
            else
                app.nanid = varargin{13};
            end
        else
            nanidx = cell(size(app.signal_all));
            for k=1:length(app.plot_cond)
                for j = 1:length(app.signal_all{k})
                    nanidx{k}{j} = (isnan(app.signal_all{k}{j}));
                end
            end
            app.nanid = nanidx;
        end
        app.showslice = 0;
        app.elecMatrix=[];
        app.elecLabels=[];
        app.elecRgb=[];
        app.eleinuse = [];
        app.V = [];
        app.m = {};
        app.order = 1:nchannels(app.D{1});
        app.sel_cond = true(length(app.plot_cond),1)';
        %app.output = hObject;
        set(app.slider1,'value',0.3);
        set(app.slider3,'enable','off');
        set(app.axes4,'visible','off');
        set(app.checkbox2,'visible','off');
        try
            set(app.popupmenu1,'Items',cellstr(app.bc_type));
        catch
        end
        set(app.ConditionDropDown,'Items',app.labels(app.plot_cond));
        % if isempty(app.atf)
        %     set(app.slider2,'enable','off');
        % end
        app.sparam = (get(app.slider1,'value'))*60;
        % Update handles structure
        % guidata(app);
        
        app.overlay=1;
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        %%%%%%!!!!!!!!!!!!!!!!!!!!!!
        try %structured data with image info
             ptimage = brain3d(app.D{1});
%             app.elecoor = ptimage.elinfo.LEPTO_coord;% app.elecoor = ptimage.native_coord;
%             app.elecLabels = ptimage.mgrid.label;%cellstr(ptimage.elect_names);
%             app.elecRgb = [0 0.4470 0.7410].*ones(size(app.elecoor));
%             app.elecMatrix=round(ptimage.mgrid.coor);%round(ptimage.MNI_coord);
%             vol = ptimage.V;
%             vol=double(vol./max(vol(:)));
%             vol=smooth3(vol,'gaussian',3);
%             app.V = vol;
%             surfpath = ptimage.cortex;
            app.D{1}.labels=ptimage.elinfo.FS_label;
            clear ptimage
        catch
        end
        
        if ~exist('ptimage','var') || isempty(ptimage)
            elecpath=[];
            if length(varargin) == 14 && ~isempty(varargin{14})
                if isfield(information,'imageDir')
                    elecpath = information.imageDir;
                end
            end
            [vol,mgridfilenames,leptoCoor, surfpath, elecpath] = get_images_ui(app.D{1},app.figure1,app.hmsg,elecpath);
            information.imageDir = elecpath;
            if ~isempty(vol) || ~isempty(surfpath)
                app.hmsg.Text = sprintf('Loading image data ...');
                pause(0.1);
            end
                %app.figure1.Visible = 'off';
            %app.figure1.Visible = 'on';
            vol=vol./max(vol(:));
            try
                vol=smooth3(vol,'gaussian',3);                
            catch
            end
            app.V = vol;
            try
                [app.elecMatrix, app.elecLabels, app.elecRgb]=mgrid2matlab(mgridfilenames);
                app.elecMatrix=round(app.elecMatrix);
            catch
            end
            try
               [filepath,subname,~]  =  fileparts(mgridfilenames);
               ln = strcat(subname,'.electrodeNames.txt');
               lnn = fullfile(filepath,ln);
               if ~exist(lnn,'file')
                    lnn2 = strcat(subname, '.electrodeNames');
                    old = fullfile(filepath,lnn2);
                    movefile(old, lnn);
                end
                labeltxt = importdata(lnn,' ');
                app.elecLabels = labeltxt(3:end);
            catch
            end
            app.elecoor = leptoCoor;
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %% rearrange the channel labels
        try
            
            if ~isempty(app.elecLabels)
                if contains(app.elecLabels{1},'_')
                    for ii=1:length( app.elecLabels)
                        app.m(ii,:)=strsplit(app.elecLabels{ii},'_');
                    end
                elseif contains(app.elecLabels{1},' ')
                    for ii=1:length( app.elecLabels)
                        sp=strsplit(app.elecLabels{ii},' ');
                        app.m(ii,1)=strcat(sp(:,3),sp(:,2));
                        app.m(ii,2)=cellstr((join(regexp(string(sp(:,1)),'[A-Z]','Match','ignorecase'),'')));
                        app.m(ii,3)=cellstr(regexp(string(sp(:,1)),'\d*','Match'));
                    end
                else
                    for ii=1:length( app.elecLabels)
                        eid(ii) = str2num(regexp(string(app.elecLabels{ii}),'\d*','Match'));
                    end
                    if max(eid)>=16
                        etype='s';
                    else
                        etype='d';
                    end
                    for ii=1:length( app.elecLabels)
                        app.m(ii,1) = cellstr(strcat(app.elecLabels{ii}(1),etype));
                        app.m(ii,2) = cellstr((join(regexp(string(app.elecLabels{ii}),'[A-Z]','Match','ignorecase'),'')));
                        app.m(ii,3) = cellstr(regexp(string(app.elecLabels{ii}),'\d*','Match'));
                    end
                end
                lb = chanlabels(app.D{1});
                random_check = randi(length(lb),1,2);
                if (~isempty(str2num(lb{random_check(1)})) &&  ~isempty(str2num(lb{random_check(2)}))) || contains(lb{random_check(1)},'iEEG')
                    mrilabel = unique(app.m(:,2));
                    eeglabelnew = relabel_tdt(lb, mrilabel);
                    for k = 1:length(app.D)
                        
                        chanlabels(app.D{k},1:length(eeglabelnew),eeglabelnew);%save the new labels
                    end
                    app.labels = eeglabelnew;
                    for ii=1:length(eeglabelnew)
                        mc = char(eeglabelnew{ii});
                        modi(ii)=string(erase(mc,[string('EEG'),string('_'),string('-'),string('.'),string(' ')]));%,string('''')]));dont erase prime
                    end
                else
                    for ii=1:length(chanlabels(app.D{1}))
                        mc = char(chanlabels(app.D{1},ii));
                        modi(ii)=string(erase(mc,[string('EEG'),string('_'),string('-'),string('.'),string(' ')]));%,string('''')]));
                    end
                end
                
%                 for ii=1:length(chanlabels(app.D{1}))
%                     mc = char(chanlabels(app.D{1},ii));
%                     modi(ii)=string(erase(mc,["EEG","_","-","."," ","'"]));
%                 end
                if size(app.m,2) ~=3
                    side = cell(size(app.m,1),1);
                    app.m = [side  app.m];
                end
                labelorder = cell(1, length(app.elecLabels));
                app.order = nan(1, length(app.elecLabels));
                for ii=1:length( app.elecLabels)
                    labelorder{ii}=find(strcmpi(strcat(app.m(ii,2),app.m(ii,3)),modi));
                    if isempty(labelorder{ii})
                        if ii < length( app.elecLabels) && ii > 1
                            next = find(strcmpi(strcat(app.m(ii+1,2),app.m(ii+1,3)),modi));
                            if ~isempty(next) && next-labelorder{ii-1}==-2
                                labelorder{ii} = labelorder{ii-1}-1;
                            elseif ~isempty(next) && next-labelorder{ii-1}==2
                                labelorder{ii} = labelorder{ii-1}+1;
                            else
                                labelorder{ii} = nan;
                            end
                        else
                            labelorder{ii} = nan;
                        end
                    elseif length(labelorder{ii})>1
                        labelorder{ii} = labelorder{ii}(end);
                        app.m(ii,2)=strcat(app.m(ii,2),'_',app.m(ii,1));
                        
                    end
                    app.order(ii) = labelorder{ii};
                end
                misind=find(isnan(app.order));
                try
                    if ~isempty(misind)
                        eind=sort(app.order);
                        eind(isnan(eind))=[];
                        check=1:(nchannels(app.D{1}));
                        check(eind)=[];%labels in edf that do not match the image data
                        fin = find(isnan(app.order));
                        compareind=app.order([fin+1 fin-1]);%Neighbors of labels in the image data that did not find a match
                        for i=1:length(check)
                            cname = lower(modi(check(i)));%channel label in edf file
                            if any(abs(check(i)-compareind)==1) && ... % For those mismatched labels, first look for neiboring labels
                                    ~contains(cname,'ref') && ...
                                    ~contains(cname,'ekg') && ...
                                    ~contains(cname,'ecg') && ...
                                    ~contains(cname,'edf')
                                found = find(abs(check(i)-compareind)==1);
                                if found > length(misind)
                                    found = found -length(misind);
                                end
                                app.order(misind(found))=check(i);
                            elseif length(find(diff(diff(misind))==0)) == length(find(diff(diff(check))==0)) && ~isempty(find(diff(diff(misind))==0, 1))
                                fc = find(diff(diff(check))==0);
                                app.order(misind)=sort(check(fc(1):(fc(end)+2)),'descend'); % If the mislabeled chan = missing chan
                                break;
                            end
                        end
                    end
                catch
                end
                if any(isnan(app.order))%if there is still mismatched label
                    answer = questdlg('', ...
        	               'Channel label mismatch', ...
    	                   'Auto','Manual','Cancel','Auto');
                    switch answer
                        case 'Auto'
                            misind2=find(isnan(app.order));
                            try
                                for k = misind2
                                    index = find_most_similar(strcat(app.m{k,2},app.m{k,3}), modi(check));
                                    index = index(end);
                                    app.order(k)=check(index);
                                    %cn = char(modi(check(index)));
                                    cn = char(chanlabels(app.D{1},check(index)));
                                    app.m{k,2} = cn(isstrprop(cn,'alpha'));
                                    check(index) = [];
                                end
                            catch
                            end
                        case 'Manual'
                            %if any(isnan(app.order))%if there is still mismatched label!!!!!
                            eind=sort(app.order);
                            eind(isnan(eind))=[];
                            check=1:(nchannels(app.D{1}));
                            check(eind)=[];
                            a = modi(check);%channel label in edf file;
                            for i = 1:length(a)
                                nums = regexp(string(a{i}),'[0-9]','Match','ignorecase');
                                a2(i) = cellstr(a{i});
                                for nn = 1:length(nums)
                                    [~,numloc] = intersect(char(a2{i}),nums{nn});
                                    a2{i}(numloc)=[];
                                end

                                %a2(i) = cellstr((join(regexp(string(a{i}),'[A-Z]','Match','ignorecase'),'')));
                                try
                                    a3(i) = cellstr(join(nums,''));
                                    %a3(i) = cellstr(regexp(string(a{i}),'\d*','Match'));
                                catch
                                    a3(i) = {[]};
                                    continue;
                                end
                            end
                            eeglabel = unique(a2);
                            mrilabel = unique(app.m(isnan(app.order),2));
                            eeglabelnew = relabel(eeglabel, mrilabel)

                            
                            ii=1;
                            for i = 1:length(eeglabel)
                                %if ~contains(eeglabel(i),{'ekg','edf','ref','annotation'},'IgnoreCase',1)
                                    id = strcmpi(a2,eeglabel(i));
                                    a2(id) = eeglabelnew(ii);
                                    
                                %end
                                ii = ii+1;
                                %a(id) = strcat(eeglabelnew(i),cellstr(regexp(string(a{i}),'\d*','Match')));
                            end
                            for i = 1:length(a)
                                a(i) = strcat(a2(i),a3(i));
                            end
                            modi(check)=a;
                            for k = 1:length(app.D)
                                chanlabels(app.D{k},1:length(modi),modi);%save the new labels
                            end
                            for ii=1:length( app.elecLabels)
                                labelorder{ii}=find(strcmpi(strcat(app.m(ii,2),app.m(ii,3)),modi));
                                if isempty(labelorder{ii})
                                    plus = cellstr(num2str(str2num(string(app.m(ii,3)))+32));
                                    labelorder{ii}=find(strcmpi(strcat(app.m(ii,2),plus),modi));
                                end
                                if isempty(labelorder{ii})
                                    plus = cellstr(num2str(str2num(string(app.m(ii,3)))+16));
                                    labelorder{ii}=find(strcmpi(strcat(app.m(ii,2),plus),modi));
                                end
                                try
                                    labelorder{ii} = labelorder{ii}(1);
                                catch
                                    continue;
                                end
                                app.order(ii) = labelorder{ii};
                            end
                        case 'Cancel'
                            app.hmsg.Text = 'Can not match channel labels with imaging data.Will continue.';
                            app.elecoor = [];
                            app.elecMatrix = [];
                            app.V = [];
                    end
                end
%                 if any(isnan(app.order))
%                     app.hmsg.Text = 'Can not match channel labels with imaging data. Will continue.';
%                     app.elecoor = [];
%                     app.elecMatrix = [];
%                     app.V = []; 
%                 end
            end
        catch
            %% A silly workaround to put the figure window in front
                app.figure1.Visible = 'off';
                app.figure1.Visible = 'on';
            %%
            app.hmsg.Text = 'Can not match channel labels with imaging data. Will continue.';
            app.elecoor = [];
            app.elecMatrix = [];
            app.V = [];
        end
        
        %% Get group names, add color if needed
        if length(unique(sum(app.elecRgb,2))) == 1
            app.elecRgb = map_color(app.m(:,2));
        end
        if ~isempty(app.elecRgb)
            dd=diff(app.elecRgb(:,1));
            dd=[0;dd];
            id=find(dd~=0);
            id2=[id-1;size( app.elecRgb,1)];
            id1=[1;id];
            app.group=[id1 id2];
            app.groupNames = app.m(id1,2);
        end
        %% Plot cortex model
        if ~isempty(app.elecoor) || ~isempty(app.elecMatrix)
            
            %axes(app.axes3);
            if isstruct(surfpath) && ~isempty(app.elecoor)
                app = plot_mesh(app, surfpath);
                set(app.slider3,'enable','on');
                app.eleinuse = app.elecoor;
                set(app.showL,'visible','on');
                set(app.showR,'visible','on');
            elseif exist(fullfile(surfpath,'rh.pial'),'file') && ~isempty(app.elecoor)
                
                app = plot_mesh(app, surfpath);
                set(app.slider3,'enable','on');
                app.eleinuse = app.elecoor;
                set(app.showL,'visible','on');
                set(app.showR,'visible','on');
            elseif ~isempty(app.V)
                set(app.checkbox2,'visible','off','value',0);
                app.showslice = 1;
                xyz=zeros(size(app.elecMatrix));
                [~,~,d3] = size(app.V);
                xyz(:,1)=app.elecMatrix(:,2);
                xyz(:,2)=app.elecMatrix(:,1);
                xyz(:,3)=d3-app.elecMatrix(:,3);
                app.eleinuse = xyz;
                sliceh = plot_3dslice(app);
                app.slice = sliceh;
                app.panel2.Visible = 'on';%plot MRI
                %app.TabGroup.Visible = 'on';
                app.Button_3.Visible = 'off';
            end
            %% add label
            app.labeltext = text(app.axes3,app.eleinuse(1,1),app.eleinuse(1,2),app.eleinuse(1,3),...
                strcat(app.m(1,2),app.m(1,3)),'FontSize',18);
            if app.showslice
                set(app.labeltext,'color',[1 1 1]);
            end
            %% add marker
            app.currele = plot3(app.axes3,app.eleinuse(1,1),app.eleinuse(1,2),app.eleinuse(1,3),...
                'o','markersize',12,'linewidth',3);
            
            rotate3d(app.axes3,'on');
            axis(app.axes3,'vis3d');
            axis(app.axes3,'off');
            
            %% compute avg activation map
            for k=1:length(app.plot_cond)
                for i = 1:length(chanlabels(app.D{1}))  
                    tp = app.signal_all{k}{i};
                    v = var(tp);
                    bad = all(isnan(app.nanid{k}{i})) + v > 5*nanmedian(v);
                    tpm = nanmean(app.signal_all{k}{i}(:,~bad),2);
                    app.actMap(k,i) = max(tpm(round(length(tpm)*0.3):end));
                end
            end
            app.ei = show_heatmap(app.axes3,[], app.actMap(1,:), app.order,app.eleinuse, app.showMap.Value, 1);%ehandles = show_heatmap(ax,ehandles,actMap,order,elecoor,show,ini)

            %app = show_heatmap(app,1);
            set(app.showMap,'visible','on');
        else
            set(app.axes3,'visible','off');
            %set(app.axes1,'position',[0.2 0.08 0.75 0.75],'units','normalized');
        end
        
        
        %% plot signal and show significant channels with suffix
        Nt = length(app.plot_cond);
        app.cc = brewermap(Nt,'Set1');%linspecer(Nt);
        %axes(app.axes1);


        labelorder = app.order(~isnan(app.order));
        %clr = repmat( [0.7 0.7 0.7],nchannels(app.D{1}),1);
        % for j = 1:length(app.plot_cond)
        %     id = app.sig_chan{j};
        %     clr(id,:) = repmat(cc(j,:),length(id),1);%make significant channels the same color as the corresponding condition
        % end
        % sig_any=[];
        % for jj = 1:length(app.plot_cond)
        %     sig_any = [sig_any;app.sig_chan{jj}];
        % end
        % sig_any = unique(sig_any);
        % sig_names = chanlabels(app.D{1},sig_any);
        
        for jj = 1:length(app.plot_cond)
            sig_names{jj} = chanlabels(app.D{1},app.sig_chan{jj});
        end
        ppre = '<HTML>';
        ppost = '</HTML>';
        pre = '<FONT color="';
        post = '</FONT>';
        for j = 1:length(labelorder)
            chanstr(j).name = chanlabels(app.D{1},labelorder(j));
            chanstr(j).Color = [179 179 179];
            suffix{j} = '';
            for jj = 1:length(app.plot_cond)
                if any(strcmp(chanstr(j).name,sig_names{jj}))
                    %suffix{j} = strcat(suffix{j},pre, rgb2Hex( round(app.cc(jj,:).*255) ), '">' ,'*' ,post);
                    suffix{j} = strcat(suffix{j},'*');
                end
            end
        end
%         ppre = '{';
%         ppost = '}';
%         pre = 'color:#';
%         post = '"';
%         for j = 1:length(labelorder)
%             chanstr(j).name = chanlabels(app.D{1},labelorder(j));
%             chanstr(j).Color = [179 179 179];
%             suffix{j} = '';
%             for jj = 1:length(app.plot_cond)
%                 if any(strcmp(chanstr(j).name,sig_names{jj}))
%                     suffix{j} = strcat(suffix{j},';',pre, lower(rgb2Hex( round(app.cc(jj,:).*255) )), ';label:"' ,'*' ,post);
%                 end
%             end
%         end
        
        listboxStr = cell(numel( chanstr ),1);
        for i = 1:numel( chanstr )
            str = strcat(ppre, pre, lower(rgb2Hex( chanstr(i).Color )), ';label:"' ,chanstr(i).name ,post ,suffix{i},ppost);
            listboxStr(i) = str;
        end
        
        %app.listbox1.Items = listboxStr;
        %app.listbox1.ValueChangedFcn = createCallbackFcn(app, @listbox1_Callback, true);
        %app.listbox1.FontName = 'Optima';
        %app.listbox1.FontSize = 16;
        %app.listbox1.FontColor = [0.502 0.502 0.502];
        %app.listbox1.Position = [16 11 102 253];
        %app.listbox1.Value = listboxStr;
        
        %app.listbox1.items = listboxStr;
        set(app.listbox1,'items',chanlabels(app.D{1},app.order(~isnan(app.order))));
        or = chanlabels(app.D{1},app.order(~isnan(app.order)));
        [win, widgetID] = mlapptools.getWebElements(app.listbox1);
        for j = 2:length(or)
            %     chanstr(j).name = chanlabels(app.D{1},labelorder(j));
            %     chanstr(j).Color = [179 179 179];
            %     suffix{j} = '';
            %pre=[',''<span>' or{j} '</span>'];
            %pre = [',''<p data-mw-index="' num2str(j) '" data-mw-tooltiptext="" class="mwListItem">' or{j}];
            pre=[',''' or{j}];
            post=[''')'];
            %post=['</p>'')'];
            dojo_style_prefix = strcat('dojo.setAttr(dojo.query("[class=''mwListItem'']")[',num2str(j-1),'],');
            for jj = 1:length(app.plot_cond)
                if any(strcmp(or(j),sig_names{jj}))
                    pre = [pre '<span style="color:#' rgb2Hex( round(app.cc(jj,:).*255) ) '">*</span>'];
                    %win.executeJS([dojo_style_prefix '''after''',txt]);
                end
            end
            txt = [pre post];
            try
            win.executeJS([dojo_style_prefix '"innerHTML"',txt]);
            catch
                ss
            end
        end
        set(app.popupmenu1,'Items',{'Zscore' 'Log'});
        switch app.bc_type
            case 'z'
                set(app.popupmenu1,'Value','Zscore');
            case 'log'
                set(app.popupmenu1,'Value','Log');
        end
        %% plot 2D slice. Store the app and electrode app
        app.slice2d_axes = plot_slice(app.elecMatrix, app.elecRgb, app.V,app.page, app.panel2);
        cla(app.axes1);
        app.hmsg.Text = '';
        app.Panel_2.Visible = 'on'; %show logo
        app.pushbutton9.Visible = 'on';
        app.pushbutton10.Visible = 'on';
        app.savebtn.Enable = 'on';
        app.export.Enable = 'on';
        app.addBtn.Enable = 'on';
        app.inspectBtn.Enable = 'on';
        app.pushbutton2.Enable = 'on';
        app.pushbutton1.Enable = 'on';
        app.Button_2.Enable = 'on';
        app.TabGroup2.Visible = 'on'; %channel list
        app.Panel_3.Visible = 'on';%smooth/artifact operations
        app.Panel_6.Visible = 'on';%3d plot
        app.tfpanel.Visible = 'on';
        app.Panel_7.Visible = 'on';
        app.Panel_15.Visible = 'on';
        app.Panel_16.Visible = 'on';
        app.Panel_10.Visible = 'on';
        app.Panel_17.Visible = 'on';
        app.Panel_18.Visible = 'on';
        app.figure1.Resize = 'on';
        app.figure1.Position = [644,81,1465,877];
        app.uipanel4.Position = [150,800,578,71];
        app.Panel_2.Position = [14,800,131,70];
        app.Panel_4.Position = [150,393,578,401];
        %app.plottab.Position = [1,1,578,401];
        %app.axes1.Position = [27,1,515,418];
        app.legendp = plot_browser(app.signal_all, app.sparam,app.labels,app.D,...
            app.window,app.plot_cond, app.order(app.page), app.yl,app.bch,app.t,1,app.cc,app.axes1);
        app.phandle = plot_pval(squeeze(app.sig_ts(:,1,:)),app.t,app.cc,app.axes1,1);
        axis(app.axes1,'normal','xy');
        app.axes1.Visible = 'on';
        app.panel2.Visible = 'on';
        app.TextArea.Visible = 'on';
        %app.tf = app.tf{1};
        close all;
        try
            stf = TF_Smooth(nanmean(app.tf{app.plot_cond(app.sel_cond(1))}{app.order(app.page)},3),'gaussian',4);
            app.tft = app.t(1):((app.t(end)-app.t(1))/(size(app.tf{1}{1},2)-1)):app.t(end);
            app.tff = 0:(app.f/size(app.tf{1}{1},2)):app.f;
            app.tff(1) = [];
            %app.tff = 1:10:180;
            app.sel_tf = 1;
            app.tfplot = imagesc(app.tfaxes,app.tft,app.tff,stf);
            %app.tfaxes.Colormap = brewermap(64,'rdbu');
            app.tfaxes.Visible = 'on';
            app.tfaxes.YDir='normal';
            app.tfaxes.YLim = [0 app.f];
            app.tfaxes.XLim = [app.t(1) app.t(end)];
            app.tfscale = app.tfaxes.CLim;
        catch
            app.tfaxes.Visible = 'off';
        end
        msg = sprintf('%s\n%s\n%s\n',...
            'You can export data to matlab, save figures, manually remove artifact, or review the results from another task in the same subject.',...
            'Switch between tabs on the left for plotting options, and selectively plot conditions.');
        app.TextArea.Value = msg;
        fsize = [150,393,578,401];
        fw = fsize(4);
        for k=length(app.plot_cond):-1:1
            app.cbh(k) = uicheckbox(app.ConditionsTab,'Text',app.labels{app.plot_cond(k)}, ...
                'fontsize',15,'fontname','optima',...
                'Value',1,'Position',...
                round([15,-(1-app.legendp(4)*1.2)*100+fw*(app.legendp(2)+(app.legendp(4)*1.5/length(app.plot_cond))*(length(app.plot_cond)-k))  ...
                fw*((app.legendp(3))*1.1)  ...
                fw*((app.legendp(4)*1.1/length(app.plot_cond)))]),...
                'ValueChangedFcn',{@checkBoxCallback,k, app});
        end
        pause(0.2);
        
        app.plot_p = 0;
        D = app.D;
        try
            save(fullfile(app.cpath,strcat('Epoched_HFB','.mat')),'D','information','-append');
        catch
            savevar.evtfile = app.cpath;
            information.tf = app.tf;
            savevar.bch = app.bch;
            savevar.beh_fp = app.signal_all;
            savevar.labels = app.labels;
            savevar.plot_cond = app.plot_cond;
            savevar.type = 2;
            savevar.bc_type = app.bc_type;
            savevar.all_nan = app.nanid;
            savevar.window = app.window;
            savevar.t = app.t;
            savevar.information = information;
            save(fullfile(path(app.D{1}),strcat('Epoched_HFB','.mat')),'-struct','savevar','-v7.3');
            clear savevar
        end
        
        app.figure1.Position = [423         266        1548         945];%expand the figure
        %pause(0.2);
        app.plottab.Position = [0,0,620,442];
        app.Button.Visible = 'on';
        %app.Button.Position = [620 2 49 48];
        app.figure1.Visible = 'off';
        app.figure1.Visible = 'on';
        app.openBtn.Enable = 'off';
        app.loadBtn.Enable = 'off';
        app.Button.Position = [580 3 34 33];
        app.Button.Parent = app.Panel_4;
app.Button_3.Parent = app.Panel_6;% guidata(app);
        app.Button_3.Position = [452 3 34 33];
        
        end

        % Value changed function: checkbox2
        function checkbox2_Callback(app, event)
    % hObject    handle to checkbox2 (see GCBO)
    % eventdata  reserved - to be defined in a future version of MATLAB
    % app    structure with handles and user data (see GUIDATA)
    app.showslice = get(hObject,'Value');
    if app.showslice
        set(app.axes4,'visible','on');
        set(app.axes3,'visible','off');
        set(app.s,'visible','on');
        xyz=zeros(size(app.elecMatrix));
        [~,~,d3] = size(app.V);
        xyz(:,1)=app.elecMatrix(:,2);
        xyz(:,2)=app.elecMatrix(:,1);
        xyz(:,3)=d3-app.elecMatrix(:,3);
        app.eleinuse = xyz;
    else
        set(app.axes4,'visible','off');
        set(app.axes3,'visible','on');
        app.elecinuse = app.elecoor;
    end
    % guidata(app);
        end

        % Button pushed function: export
        function export_Callback(app, event)
    % hObject    handle to export (see GCBO)
    % eventdata  reserved - to be defined in a future version of MATLAB
    % handles    structure with handles and user data (see GUIDATA)
    assignin ('base','hfb',app.signal_all);
    assignin('base','pval',app.sig_pval);
    app.TextArea.Value = '----Data exported to workspace.';
    %disp('-------------Data exported to workspace--------------')
    % guidata(app);
        end

        % Button pushed function: inspectBtn
        function inspectBtn_Callback(app, event)
            % hObject    handle to inspectBtn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            labelorder=app.plot_cond(app.sel_cond);
            allt=app.signal_all(app.sel_cond);
            alln=app.nanid(app.sel_cond);
            sel=find(app.sel_cond);
            app.TextArea.Value = 'Check data quality trial by trial. You can manually select data segments to exclude by clicking on the trial first (axes) and drag your mouse. Choose different conditions from dropmenu. Click "Back" when done.';
            newnan = data_inspection(allt,alln,app.order(app.page),app.labels(labelorder),app.window(round(app.t*1000)==0));
            app.TextArea.Value = 'Bad indicies updated';
            pause(1);
            msg = sprintf('%s\n%s\n%s\n',...
                'You can export data to matlab, save figures, manually remove artifact, or review the results from another task in the same subject.',...
                'Switch between tabs on the left for plotting options, and selectively plot conditions.');
            app.TextArea.Value = msg;
            for k=1:length(labelorder)
                for j = 1:length(app.signal_all{1})
                    nanidx = logical(newnan{k}{j});
                    app.nanid{sel(k)}{j}=nanidx;
                    try
                        app.signal_all{sel(k)}{j}(nanidx)=nan;
                    catch
                        app.signal_all{sel(k)}{j}(:,end+1) = nan;
                    end
                    %app.signal_all{k}{j} = fillmissing(app.signal_all{k}{j},'movmean',param*10);
                end
            end
            %disp('.');
            %disp('-----------Done------------');
            %axes(app.axes1);
            plot_browser(app.signal_all(app.sel_cond), app.sparam,app.labels,app.D,...
                app.window,app.plot_cond(app.sel_cond), app.order(app.page), app.yl,app.bch,app.t,1,app.cc(app.sel_cond,:),app.axes1);
            % guidata(app);
        end

        % Value changed function: listbox1
        function listbox1_Callback(app, event)
            % hObject    handle to listbox1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            selchan = get(app.listbox1,'Value');
            allchan = get(app.listbox1,'Items');
            
            app.page = find(strcmp(selchan,allchan));
            app.page = app.page(1);
            
            if app.showslice
                move_slice(app);
            end
            if ~isempty(app.eleinuse)
                set(app.labeltext,'position',...
                    [app.eleinuse(app.page,1) app.eleinuse(app.page,2) app.eleinuse(app.page,3)+2],...
                    'string',string(strcat(app.m(app.page,2),app.m(app.page,3))));
                set(app.currele,'xdata',...
                    app.eleinuse(app.page,1),'ydata' ,app.eleinuse(app.page,2),'zdata' ,app.eleinuse(app.page,3));
            end
            plot_browser(app.signal_all(app.sel_cond), app.sparam,app.labels,app.D,...
                app.window,app.plot_cond(app.sel_cond), app.order(app.page), app.yl,...
                app.bch,app.t,1,app.cc(app.sel_cond,:),app.axes1);
            app.phandle = plot_pval(squeeze(app.sig_ts(:,app.order(app.page),:)),app.t,app.cc,app.axes1,app.ShowStatsCheckBox.Value);
            switch app.sigoverlay
                case 2
                    plot_browser(app.addsig(app.sigoverlay-1).signal_all, app.sparam,app.addsig(app.sigoverlay-1).labels,...
                        app.addsig(app.sigoverlay-1).D,...
                        app.addsig(app.sigoverlay-1).window,...
                        app.addsig(app.sigoverlay-1).plot_cond,...
                        app.order(app.page), ...
                        app.yl,app.bch,app.addsig(app.sigoverlay-1).t,...
                        1,[],app.axes1a);
                case 3
                    plot_browser(app.addsig(app.sigoverlay-2).signal_all, app.sparam,app.addsig(app.sigoverlay-2).labels,...
                        app.addsig(app.sigoverlay-2).D,...
                        app.addsig(app.sigoverlay-2).window,...
                        app.addsig(app.sigoverlay-2).plot_cond,...
                        app.order(app.page), ...
                        app.yl,app.bch,app.addsig(app.sigoverlay-2).t,...
                        1,[],app.axes1b);
            end
            move_2dslice(app.elecMatrix, app.elecRgb, app.V,app.page, app.slice2d_axes);
            try
                set(app.slice2d_axes(3),'BackgroundColor',[0.98 0.98 0.98]);
            catch
            end
            % = app.tf{1};
            try
                pc = app.plot_cond(app.sel_cond);
                stf = TF_Smooth(nanmean(app.tf{pc(app.sel_tf)}{app.order(app.page)},3),'gaussian',4);
                app.tfplot.CData = (stf);
                app.tfaxes.CLim = app.tfscale;
                %imagesc(app.tfaxes,stf);
                %app.tfaxes.YDir='normal';
                %app.tfaxes.Visible = 'on';
            catch
                app.tfaxes.Visible = 'off';
            end
            % guidata(app);
        end

        % Value changed function: popupmenu1
        function popupmenu1_Callback(app, event)
            % hObject    handle to popupmenu1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            bctype = get(app.popupmenu1,'value');
            bc_old = app.bc_type;
            %     switch bctype
            %         case 1
            %             app.bc_type = 'z';
            %         case 2
            %             app.bc_type = 'log';
            %     end
            %     if strcmp(bctype,bc_old)
            %         return;
            %     end
            signal_other = getappdata(app.figure1, 'signal_other');
            if isempty(signal_other{1})
                try
                    signal_other = evalin('base', 'Signal2');
                    signal_other = arrange(signal_other, app);
                catch
                    switch bc_old
                        case 'z'
                            bcv = 'Zscore';
                        case 'log'
                            bcv = 'Log';
                    end
                    set(app.popupmenu1, 'value',bcv);
                   	return;
                end
            end
            setappdata(app.figure1,'signal_other',app.signal_backup);
            app.signal_all = signal_other;
            app.signal_backup = signal_other;
            %axes(app.axes1);
            plot_browser(app.signal_all(app.sel_cond), app.sparam,app.labels,app.D,...
                app.window,app.plot_cond(app.sel_cond), app.order(app.page), app.yl,app.bch,app.t,1,app.cc(app.sel_cond,:),app.axes1);
            % guidata(app);
            app.phandle = plot_pval(squeeze(app.sig_ts(:,app.order(app.page),:)),app.t,app.cc,app.axes1,app.ShowStatsCheckBox.Value);
        end

        % Button pushed function: pushbutton10
        function pushbutton10_Callback(app, event)
            % hObject    handle to pushbutton10 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.yl(2) = app.yl(2)*0.9;
            app.yl(1) = app.yl(1)*0.9;
            %axes(app.axes1);
            app.axes1.YLim=app.yl;
        end

        % Button pushed function: pushbutton1
        function pushbutton1_Callback(app, event)
            % hObject    handle to pushbutton1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if app.page < length(app.order)
                app.page=app.page+1;
                
                %plot_slice(app.elecMatrix, app.elecRgb, app.V,app.page)
                if app.showslice
                    move_slice(app);
                elseif ~isempty(app.m) && ~strcmp(app.m(app.page,1),app.m(app.page-1,1))
                    %axes(app.axes3);
                    %try
                    %app.axes3.View=app.eleinuse(app.page,:);
                    %catch
                    %end
                    %set(app.light,'position',app.eleinuse(app.page,:));
                end
                if ~isempty(app.eleinuse)
                    set(app.labeltext,'position',...
                        [app.eleinuse(app.page,1) app.eleinuse(app.page,2) app.eleinuse(app.page,3)+2],...
                        'string',string(strcat(app.m(app.page,2),app.m(app.page,3))));
                    set(app.currele,'xdata',...
                        app.eleinuse(app.page,1),'ydata' ,app.eleinuse(app.page,2),'zdata' ,app.eleinuse(app.page,3));
                end
                %axes(app.axes1);
                plot_browser(app.signal_all(app.sel_cond), app.sparam,app.labels,app.D,...
                    app.window,app.plot_cond(app.sel_cond), app.order(app.page), ...
                    app.yl,app.bch,app.t,1,app.cc(app.sel_cond,:),app.axes1);
                app.phandle = plot_pval(squeeze(app.sig_ts(:,app.order(app.page),:)),app.t,app.cc,app.axes1,app.ShowStatsCheckBox.Value);
                switch app.sigoverlay
                    case 2
                        plot_browser(app.addsig(app.sigoverlay-1).signal_all, app.sparam,app.addsig(app.sigoverlay-1).labels,...
                            app.addsig(app.sigoverlay-1).D,...
                            app.addsig(app.sigoverlay-1).window,...
                            app.addsig(app.sigoverlay-1).plot_cond,...
                            app.order(app.page), ...
                            app.yl,app.bch,app.addsig(app.sigoverlay-1).t,...
                            1,[],app.axes1a);
                    case 3
                        plot_browser(app.addsig(app.sigoverlay-2).signal_all, app.sparam,app.addsig(app.sigoverlay-2).labels,...
                            app.addsig(app.sigoverlay-2).D,...
                            app.addsig(app.sigoverlay-2).window,...
                            app.addsig(app.sigoverlay-2).plot_cond,...
                            app.order(app.page), ...
                            app.yl,app.bch,app.addsig(app.sigoverlay-2).t,...
                            1,[],app.axes1b);
                end
                move_2dslice(app.elecMatrix, app.elecRgb, app.V,app.page, app.slice2d_axes);
                try
                    set(app.slice2d_axes(3),'BackgroundColor',[0.98 0.98 0.98]);
                catch
                end
            end
            set(app.listbox1,'Value',chanlabels(app.D{1},app.order(app.page)));
            try
                pc = app.plot_cond(app.sel_cond);
                stf = TF_Smooth(nanmean(app.tf{pc(app.sel_tf)}{app.order(app.page)},3),'gaussian',4);
                app.tfplot.CData = stf;
                app.tfaxes.CLim = app.tfscale;
                %imagesc(app.tfaxes,stf);
                %app.tfaxes.YDir='normal';
                %app.tfaxes.Visible = 'on';
            catch
            end
            % guidata(app);
        end

        % Button pushed function: pushbutton2
        function pushbutton2_Callback(app, event)
            % hObject    handle to pushbutton2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if app.page > 1
                app.page=app.page-1;
                
                %plot_slice(app.elecMatrix, app.elecRgb, app.V,app.page)
                if app.showslice
                    move_slice(app);
                elseif ~isempty(app.m) && ~strcmp(app.m(app.page,1),app.m(app.page+1,1))
                    %axes(app.axes3);
                    %app.axes3.View=app.eleinuse(app.page,:);
                    %set(app.light,'position',app.eleinuse(app.page,:));
                end
                if ~isempty(app.eleinuse)
                    set(app.labeltext,'position',...
                        [app.eleinuse(app.page,1) app.eleinuse(app.page,2) app.eleinuse(app.page,3)],...
                        'string',string(strcat(app.m(app.page,2),app.m(app.page,3))));
                    set(app.currele,'xdata',...
                        app.eleinuse(app.page,1),'ydata' ,app.eleinuse(app.page,2),'zdata' ,app.eleinuse(app.page,3));
                end
                move_2dslice(app.elecMatrix, app.elecRgb, app.V,app.page, app.slice2d_axes);
                try
                    set(app.slice2d_axes(3),'BackgroundColor',[0.98 0.98 0.98]);
                catch
                end
                %axes(app.axes1);
                plot_browser(app.signal_all(app.sel_cond), app.sparam,app.labels,app.D,...
                    app.window,app.plot_cond(app.sel_cond), app.order(app.page), app.yl,app.bch,app.t,1,app.cc(app.sel_cond,:),app.axes1);
                app.phandle = plot_pval(squeeze(app.sig_ts(:,app.order(app.page),:)),app.t,app.cc,app.axes1,app.ShowStatsCheckBox.Value);
                switch app.sigoverlay
                    case 2
                        plot_browser(app.addsig(app.sigoverlay-1).signal_all, app.sparam,app.addsig(app.sigoverlay-1).labels,...
                            app.addsig(app.sigoverlay-1).D,...
                            app.addsig(app.sigoverlay-1).window,...
                            app.addsig(app.sigoverlay-1).plot_cond,...
                            app.order(app.page), ...
                            app.yl,app.bch,app.addsig(app.sigoverlay-1).t,...
                            1,[],app.axes1a);
                    case 3
                        plot_browser(app.addsig(app.sigoverlay-2).signal_all, app.sparam,app.addsig(app.sigoverlay-2).labels,...
                            app.addsig(app.sigoverlay-2).D,...
                            app.addsig(app.sigoverlay-2).window,...
                            app.addsig(app.sigoverlay-2).plot_cond,...
                            app.order(app.page), ...
                            app.yl,app.bch,app.addsig(app.sigoverlay-2).t,...
                            1,[],app.axes1b);
                end
                
            end
            set(app.listbox1,'Value',chanlabels(app.D{1},app.order(app.page)));
            %app.tf = app.tf{1};
            
            try
                pc = app.plot_cond(app.sel_cond);
                stf = TF_Smooth(nanmean(app.tf{pc(app.sel_tf)}{app.order(app.page)},3),'gaussian',4);
                app.tfplot.CData = stf;
                app.tfaxes.CLim = app.tfscale;
                %imagesc(app.tfaxes,stf);
                %app.tfaxes.YDir='normal';
                %app.tfaxes.Visible = 'on';
            catch
            end
            %set(app.listbox1,'ItemsData',app.page);
            % guidata(app);
        end

        % Button pushed function: pushbutton9
        function pushbutton9_Callback(app, event)
            % hObject    handle to pushbutton9 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.yl(2) = app.yl(2)*1.1;
            app.yl(1) = app.yl(1)*1.1;
            %axes(app.axes1);
            app.axes1.YLim=app.yl;
        end

        % Button pushed function: savebtn
        function savebtn_Callback(app, event)
            % hObject    handle to savebtn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            rtask = strcat('Results_',app.task);
            resultdname = fullfile(path(app.D{1}),rtask);
            if ~exist(resultdname,'dir')
                mkdir(resultdname);
            end
            f = figure;
            if ~isempty(app.elecMatrix)
                set(f,'Position',[400 600 700 700]);
                %set(f,'Position',[400 600 700 500]);
                set(f,'color',[1 1 1]);
                app.TextArea.Value = '----Saving all figures. Check command window for progress.';
                %disp('-------------Saving all figures--------------')
                ax1 = axes('Position',[0.1 0.45 0.85 0.5]);
                for i = 1:length(app.order)
                    chanid = app.order(i);
                    name=char(chanlabels(app.D{1},app.order(i)));
                    filename = strcat(app.sname,name,'_',app.task);
                    progress(i,length(app.order),80,0)
                    %axes(ax1);
                    yli = app.yl;
                    
                    plot_browser(app.signal_all(app.sel_cond), app.sparam,app.labels,app.D,...
                        app.window,app.plot_cond(app.sel_cond), app.order(i), app.yl,app.bch,app.t,1,app.cc(app.sel_cond,:),ax1);
                    set(ax1,'color',[1 1 1]);
                    if i==1
                        slice2d_axes_f = plot_slice_fig(app.elecMatrix, app.elecRgb, app.V,i);
                    else
                        move_2dslice(app.elecMatrix, app.elecRgb, app.V,i, slice2d_axes_f);
                    end
                    %plot_slice(app.elecMatrix, app.elecRgb, app.V,i);
                    print(f, fullfile(resultdname,filename),'-opengl','-r300','-dpng');
                    
                end
            else
                set(f,'Position',[400 600 700 500]);
                set(f,'color',[1 1 1]);
                %disp('-------------Saving all figures--------------')
                app.TextArea.Value = '----Saving all figures. Check command window for progress.';
                ax1 = axes('Position',[0.1 0.1 0.85 0.85],'color',[1 1 1]);
                for i = 1:length(app.order)
                    chanid = app.order(i);
                    name=char(chanlabels(app.D{1},app.order(i)));
                    filename = strcat(app.sname,name,'_',app.task);
                    progress(i,length(app.order),80,0)
                    
                    %axes(ax1);
                    plot_browser(app.signal_all(app.sel_cond), app.sparam,app.labels,app.D,...
                        app.window,app.plot_cond(app.sel_cond), app.order(i), app.yl,app.bch,app.t,1,app.cc(app.sel_cond,:),ax1);
                    set(ax1,'color',[1 1 1]);
                    print(f, fullfile(resultdname,filename),'-opengl','-r300','-dpng');
                end
            end
            msg = sprintf('%s\n%s\n%s\n',...
                'Complete.',...
                'Figure directory:',resultdname);
            app.TextArea.Value = msg;
            pause(0.2);
        end

        % Value changed function: slider1
        function slider1_Callback(app, event)
            % hObject    handle to slider1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            sv = get(app.slider1,'Value')+0.02;
            app.sparam = sv*60;
            %axes(app.axes1);
            cla(app.axes1);
            plot_browser(app.signal_all(app.sel_cond), app.sparam,app.labels,app.D,...
                app.window,app.plot_cond(app.sel_cond), app.order(app.page), app.yl,app.bch,app.t,1,app.cc(app.sel_cond,:),app.axes1);
            app.phandle = plot_pval(squeeze(app.sig_ts(:,app.order(app.page),:)),app.t,app.cc,app.axes1,app.ShowStatsCheckBox.Value);
                % guidata(app);
        end

        % Value changed function: slider2
        function slider2_Callback(app, event)
            % hObject    handle to slider2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            param = get(app.slider2,'Value')*100;
            app.signal_all=app.signal_backup;
            se = strel('line',param*2,90);
            for k=1:length(app.plot_cond)
                for j = 1:length(app.signal_all{k})
                    progress(j,length(app.signal_all{k})*length(app.plot_cond),80,0)
                    nanidx = app.nanid{k}{j};
                    results=logical(imdilate(nanidx,se));
                    try
                        app.signal_all{k}{j}(results)=nan;
                    catch
                        app.signal_all{k}{j}(:,end+1) = nan;
                    end
                    %app.signal_all{k}{j} = fillmissing(app.signal_all{k}{j},'movmean',param*10);
                end
            end
            %disp('-----------Done------------');
            %axes(app.axes1);
            plot_browser(app.signal_all(app.sel_cond), app.sparam,app.labels,app.D,...
                app.window,app.plot_cond(app.sel_cond), app.order(app.page), app.yl,app.bch,app.t,1,app.cc(app.sel_cond,:),app.axes1);
            app.phandle = plot_pval(squeeze(app.sig_ts(:,app.order(app.page),:)),app.t,app.cc,app.axes1,app.ShowStatsCheckBox.Value);
                % guidata(app);
        end

        % Value changed function: slider3
        function slider3_Callback(app, event)
            % hObject    handle to slider3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.alpha=get(app.slider3,'Value');
            
            set(app.lh,'facealpha',app.alpha);
            set(app.rh,'facealpha',app.alpha);
            
            % guidata(app);
        end

        % Value changed function: Switch
        function atf_off(app, event)
            value = app.Switch.Value;
            if strcmp(value,'Off')
                app.signal_all=app.signal_backup;
                set(app.slider2,'enable','off');
                plot_browser(app.signal_all(app.sel_cond), app.sparam,app.labels,app.D,...
                    app.window,app.plot_cond(app.sel_cond), app.order(app.page), app.yl,app.bch,app.t,1,app.cc(app.sel_cond,:),app.axes1);
            app.phandle = plot_pval(squeeze(app.sig_ts(:,app.order(app.page),:)),app.t,app.cc,app.axes1,app.ShowStatsCheckBox.Value);
            else
                set(app.slider2,'enable','on');
                slider2_Callback(app, event);
            end
        end

        % Button pushed function: openBtn
        function openBtnButtonPushed(app, event)
                msg = sprintf('%s\n%s\n%s\n%s\n%s\n',' Please select .edf files','','and behavioral files');
                app.hmsg.Text = msg;
                filename = spm_select(inf,'any','Select file to convert',{},pwd,'.edf'); 
                [sodata] = spm_select(inf,'mat','Select SODATA for file',{},pwd,'.mat');
                if isempty(filename) || isempty(sodata)
                    return;
                end
                close(app.figure1);
                LBCN_preprocessing_new(filename,sodata);
        end

        % Button pushed function: loadBtn
        function loadBtnButtonPushed(app, event)
            msg = sprintf('%s\n%s\n%s\n%s\n%s\n',' Please select eventsSODATA, or','','Epoched_data, or','','saved results');
            app.hmsg.Text = msg;
            [filename,pathname] = uigetfile({'*.mat','Data format (*.mat)'},...
                'MultiSelect', 'on');
            fname = fullfile(pathname,filename);
            if isempty(fname) || isempty(filename)
                app.hmsg.Text = '';
                return;
            else
                close(app.figure1);
                if contains(fname,'Epoched','IgnoreCase',1)
                    LBCN_plot_HFB(fname);
                else
                    view_results(fname);
                end
            end
%             
%             if isempty(fname) || isempty(filename)
%                 return;
%             end
%             close(app.figure1);
%             if contains(fname,'Epoched','IgnoreCase',1)
%                 LBCN_plot_HFB(fname);
%             else
%                 view_results(fname);
%             end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
% 
%             close(app.figure1);
%             plot_window_App;
            
            homeimg = imread('home1.png');
                imshow(homeimg,'parent',app.axes1);
                wmsg = sprintf('%s\n%s\n%s\n%s\n%s\n%s\n%s\n%s','WELCOME TO LBCN,','','To start new processing: OPEN edf',...
                    '','To view past results: LOAD','','Click here for help');
                app.hmsg = uilabel(app.figure1,'Text',wmsg,'Position',[310 95 250 200]);
                app.hmsg.FontName = 'Helvetica-Narrow';
                app.hmsg.FontSize = 14;
                app.hmsg.FontColor = [1 1 1];
                app.hmsg.HorizontalAlignment = 'center';
                app.logopos = get(app.Panel_2,'Position');
                app.buttonpos = get(app.uipanel4,'Position');
                app.axes1pos = get(app.axes1,'Position');
                set(app.figure1,'position',[140+644 481 590 477],'resize','off');
                set(app.uipanel4,'position',[5 400 578 71]);
                app.figure1.Name = 'Welcome to LBCN';
                %app.inipos = get(app.Panel_4,'position');
                set(app.Panel_4,'position',[5 8 app.inipos(3) app.inipos(4)]);
                app.Panel_10.Visible = 'off';
                app.Panel_17.Visible = 'off';
                app.Panel_18.Visible = 'off';
                app.pushbutton9.Visible = 'off';
                app.pushbutton10.Visible = 'off';
                app.TabGroup2.Visible = 'off';
                app.Panel_3.Visible = 'off';
                app.tfpanel.Visible = 'off';
                app.Panel_6.Visible = 'off';
                app.panel2.Visible = 'off';
                app.TextArea.Visible = 'off';
                delete(app.cbh);
                app.export.Enable = 'off';
                app.inspectBtn.Enable = 'off';
                app.savebtn.Enable = 'off';
                app.pushbutton2.Enable = 'off';
                app.pushbutton1.Enable = 'off';
                app.Button_2.Enable = 'off';
                app.addBtn.Enable = 'off';
                app.Button.Visible = 'off';
                app.openBtn.Enable = 'on';
                app.loadBtn.Enable = 'on';
                app.overlay = 1;
                set(app.showL,'visible','on');
                set(app.showR,'visible','on');
                %app.plottab.Visible = 'off';
                delete(app.plot2);
                delete(app.plot3);
        end

        % Button pushed function: addBtn
        function addBtnButtonPushed(app, event)
            app.TextArea.Value = 'Choose results of anther task in the same subject.';
            pause(0.2);
            refsig = LBCN_plot_HFB([],[],[],[],[],[],[],[],[],[],[],[],[],1);
            
            app.sigoverlay = app.sigoverlay+1;
            app.addsig(app.sigoverlay-1).signal_all=refsig{1};
            app.addsig(app.sigoverlay-1).labels=refsig{2};
            app.addsig(app.sigoverlay-1).D=refsig{3};
            app.addsig(app.sigoverlay-1).window=refsig{4};
            app.addsig(app.sigoverlay-1).plot_cond=refsig{5};
            app.addsig(app.sigoverlay-1).t=refsig{6};
            app.addsig(app.sigoverlay-1).all_plot2=refsig{7};
            app.addsig(app.sigoverlay-1).bc_type=refsig{8};
            app.addsig(app.sigoverlay-1).all_nan=refsig{9};
            try
                    [taskadded,~] = get_info(path(app.addsig(app.sigoverlay-1).D{1}));
                    if isempty(taskadded)
                        prompt = {'Enter Task Name:'};
                    title = 'Info';
                    dims = [1 35];
                    definput = {' '};
                    answer = inputdlg(prompt,title,dims,definput);
                    taskadded = answer{1};
                    end
                        
            catch
                    prompt = {'Enter Task Name:'};
                    title = 'Info';
                    dims = [1 35];
                    definput = {' '};
                    answer = inputdlg(prompt,title,dims,definput);
                    taskadded = answer{1};
            end
            
            %plot_browser(app.signal_all(app.sel_cond), app.sparam,app.labels,app.D,...
            %app.window,app.plot_cond(app.sel_cond), app.order(app.page), app.yl,app.bch,app.t,1,app.cc(app.sel_cond,:),app.axes1);
            switch app.sigoverlay
                case 2
                    app.axes1pos=get(app.axes1,'position');
                    app.plot1.ForegroundColor = [0.85 0.33 0.1];
                    %set(app.plottab,'visible','on');
                    %app.axes1.Parent=app.plot1;
                    %app.axes1(1).Position = app.axes1pos;
                    app.plot2 = uitab(app.plottab);
                    app.plot2.Title = taskadded;
                    app.plot2.ForegroundColor = [0.502 0.502 0.502];
                    app.axes1a=uiaxes(app.plot2);
                    app.axes1a.Position = app.axes1pos;
                    plot_browser(app.addsig(app.sigoverlay-1).signal_all, app.sparam,app.addsig(app.sigoverlay-1).labels,...
                    app.addsig(app.sigoverlay-1).D,...
                        app.addsig(app.sigoverlay-1).window,...
                        app.addsig(app.sigoverlay-1).plot_cond,...
                        app.order(app.page), ...
                        app.yl,app.bch,app.addsig(app.sigoverlay-1).t,...
                        1,[],app.axes1a);
                case 3
                    app.plot3 = uitab(app.plottab);
                    app.plot3.Title = taskadded;
                    app.plot3.ForegroundColor = [0.502 0.502 0.502];
                    %set(app.plot3,'visible','on');
                    app.axes1b=uiaxes(app.plot3);
                    plot_browser(app.addsig(app.sigoverlay-2).signal_all, app.sparam,app.addsig(app.sigoverlay-2).labels,...
                    app.addsig(app.sigoverlay-2).D,...
                        app.addsig(app.sigoverlay-2).window,...
                        app.addsig(app.sigoverlay-2).plot_cond,...
                        app.order(app.page), ...
                        app.yl,app.bch,app.addsig(app.sigoverlay-2).t,...
                        1,[],app.axes1b);

            end
            app.TextArea.Value = 'Data loaded. Switch between tabs to see signal from different tasks.';
            pause(1);
            msg = sprintf('%s\n%s\n%s\n',...
            'You can export data to matlab, save figures, manually remove artifact, or review the results from another task in the same subject.',...
            'Switch between tabs on the left for plotting options, and selectively plot conditions.');
            app.TextArea.Value = msg;
        end

        % Value changed function: popupmenu1_2
        function popupmenu1_2ValueChanged(app, event)
            value = app.popupmenu1_2.Value;
            Nt = length(app.plot_cond);
            switch value
                case 'Set1'
                    app.cc = brewermap(Nt,'Set1');%linspecer(Nt);
                case 'Set2'
                    app.cc = brewermap(Nt,'Set2');%linspecer(Nt);
                case 'Set3'
                    app.cc = linspecer(Nt);
                case 'Set4'
                    app.cc = brewermap(Nt,'Dark2');
                case 'Paired'
                    app.cc = brewermap(Nt,'Paired');
                case 'Parula'
                    app.cc = colormap('parula');
            end
            close all;
            plot_browser(app.signal_all(app.sel_cond), app.sparam,app.labels,app.D,...
                app.window,app.plot_cond(app.sel_cond), app.order(app.page), app.yl,app.bch,app.t,1,app.cc(app.sel_cond,:),app.axes1);
            app.phandle = plot_pval(squeeze(app.sig_ts(:,app.order(app.page),:)),app.t,app.cc,app.axes1,app.ShowStatsCheckBox.Value);
        end

        % Value changed function: ConditionDropDown
        function ConditionDropDownValueChanged(app, event)
            value = app.ConditionDropDown.Value;
            list = app.labels(app.plot_cond(app.sel_cond));
            app.sel_tf = find(strcmpi(value,list));
            try
                pc = app.plot_cond(app.sel_cond);
                stf = TF_Smooth(nanmean(app.tf{pc(app.sel_tf)}{app.order(app.page)},3),'gaussian',4);
                app.tfplot.CData = stf;
                app.tfaxes.CLim = app.tfscale;
                %imagesc(app.tfaxes,stf);
                %app.tfaxes.YDir='normal';
                %app.tfaxes.Visible = 'on';
            catch
            end
            try
                currcond=find(app.sel_cond);
                app.ei = show_heatmap(app.axes3,app.ei, app.actMap(currcond(app.sel_tf),:), app.order,app.eleinuse, app.showMap.Value, 0);
            catch
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            f = figure;
            if ~isempty(app.elecMatrix)
                set(f,'Position',[400 600 700 700]);
                %set(f,'Position',[400 600 700 500]);
                set(f,'color',[1 1 1]);
                app.TextArea.Value = '----Saving all figures. Check command window for progress.';
                %disp('-------------Saving all figures--------------')
                ax1 = axes('Position',[0.1 0.45 0.85 0.5]);
                for i = (app.page)
                    chanid = app.order(i);
                    name=char(chanlabels(app.D{1},app.order(i)));
                    filename = strcat(app.sname,name,'_',app.task);
                    progress(i,length(app.order),80,0)
                    %axes(ax1);
                    plot_browser(app.signal_all(app.sel_cond), app.sparam,app.labels,app.D,...
                        app.window,app.plot_cond(app.sel_cond), app.order(i), app.yl,app.bch,app.t,1,app.cc(app.sel_cond,:),ax1);
                    set(ax1,'color',[1 1 1]);
                    %if i==1
                        slice2d_axes_f = plot_slice_fig(app.elecMatrix, app.elecRgb, app.V,i);
                    %else
                    %    move_2dslice(app.elecMatrix, app.elecRgb, app.V,i, slice2d_axes_f);
                    %end
                    %plot_slice(app.elecMatrix, app.elecRgb, app.V,i);
                end
            else
                set(f,'Position',[400 600 700 500]);
                set(f,'color',[1 1 1]);
                %disp('-------------Saving all figures--------------')
                app.TextArea.Value = '----Saving all figures. Check command window for progress.';
                ax1 = axes('Position',[0.1 0.1 0.85 0.85],'color',[1 1 1]);
                for i = (app.page)
                    chanid = app.order(i);
                    name=char(chanlabels(app.D{1},app.order(i)));
                    filename = strcat(app.sname,name,'_',app.task);
                    progress(i,length(app.order),80,0)
                    
                    %axes(ax1);
                    plot_browser(app.signal_all(app.sel_cond), app.sparam,app.labels,app.D,...
                        app.window,app.plot_cond(app.sel_cond), app.order(i), app.yl,app.bch,app.t,1,app.cc(app.sel_cond,:),ax1);
                    set(ax1,'color',[1 1 1]);
                end
            end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app = plot_mesh_fig(app);
        end

        % Value changed function: ShowStatsCheckBox
        function ShowStatsCheckBoxValueChanged(app, event)
            value = app.ShowStatsCheckBox.Value;
            for i = 1:length(app.phandle)
                set(app.phandle(i),'visible', value);
            end
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.tfscale = app.tfscale*1.1;
            app.tfaxes.CLim = app.tfscale;
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            app.tfscale = app.tfscale*0.9;
            app.tfaxes.CLim = app.tfscale;
        end

        % Value changed function: showL
        function showLValueChanged(app, event)
            value = app.showL.Value;
            set(app.lh,'Visible',value);
           
        end

        % Value changed function: showR
        function showRValueChanged(app, event)
            value = app.showR.Value; 
            set(app.rh,'Visible',value);
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
          
            stf = app.tfplot.CData;
            assignin ('base','curr_tf',stf);
        end

        % Value changed function: showMap
        function showMapValueChanged(app, event)
            value = app.showMap.Value;
            if value == 1
                alllabels = app.labels(app.plot_cond(app.sel_cond));
                current = strcmpi(alllabels,app.ConditionDropDown.Value);
                %currcond=app.plot_cond(app.sel_cond);
                
                %try
                    app.ei = show_heatmap(app.axes3,app.ei, app.actMap(current,:), app.order,app.eleinuse, app.showMap.Value, 0);
                    for i =1:length(app.e)
                        set(app.e(i),'visible','off');
                        try
                            if isfield(app.curr_elec(i),'side')
                                app.curr_elec(i).side.Visible = 'off';
                                %set(app.ei(i),'markersize',20);
                            end
                        catch
                        end
                    end
                %catch
                %end
%                 for i =1:length(app.ei)
%                     %set(app.ei,'visible','on','markerfacecolor',);
%                 end
            else
                for i =1:length(app.e)
                    set(app.e(i),'visible','on');
                    try
                        if isfield(app.curr_elec(i),'side')
                            app.curr_elec(i).side.Visible = 'on';
                        end
                    catch
                    end
                end
                try
                    for i =1:length(app.ei)
                        set(app.ei(i),'visible','off');
                    end
                catch
                end
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure;
            app.figure1.Name = 'Untitled';
            app.axes4 = uiaxes(app.figure1);
            app.axes4.XLim = [0 1];
            app.axes4.YLim = [0 1];
            app.axes4.ZLim = [0 1];
            app.axes4.CLim = [0 1];
            app.axes4.ALim = [0 1];
            app.axes4.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.axes4.XTickLabel = {'0  '; '0.2'; '0.4'; '0.6'; '0.8'; '1  '};
            app.axes4.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.axes4.YTickLabel = {'0  '; '0.2'; '0.4'; '0.6'; '0.8'; '1  '};
            app.axes4.ZTick = [0 0.5 1];
            app.axes4.ZTickLabel = '';
            app.axes4.Visible = 'off';
            app.uipanel4 = uipanel(app.figure1);
            app.savebtn = uibutton(app.uipanel4, 'push');
            app.savebtn.ButtonPushedFcn = createCallbackFcn(app, @savebtn_Callback, true);
            app.savebtn.Icon = 'save2.png';
            app.savebtn.Enable = 'off';
            app.savebtn.Text = '';
            app.export = uibutton(app.uipanel4, 'push');
            app.export.ButtonPushedFcn = createCallbackFcn(app, @export_Callback, true);
            app.export.Icon = 'export.png';
            app.export.Enable = 'off';
            app.export.Text = '';
            app.inspectBtn = uibutton(app.uipanel4, 'push');
            app.inspectBtn.ButtonPushedFcn = createCallbackFcn(app, @inspectBtn_Callback, true);
            app.inspectBtn.Icon = 'ins.png';
            app.inspectBtn.Enable = 'off';
            app.inspectBtn.Text = '';
            app.pushbutton2 = uibutton(app.uipanel4, 'push');
            app.pushbutton2.ButtonPushedFcn = createCallbackFcn(app, @pushbutton2_Callback, true);
            app.pushbutton2.Icon = 'a2.png';
            app.pushbutton2.Enable = 'off';
            app.pushbutton2.Text = '';
            app.pushbutton1 = uibutton(app.uipanel4, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1_Callback, true);
            app.pushbutton1.Icon = 'a1.png';
            app.pushbutton1.Enable = 'off';
            app.pushbutton1.Text = '';
            app.loadBtn = uibutton(app.uipanel4, 'push');
            app.loadBtn.ButtonPushedFcn = createCallbackFcn(app, @loadBtnButtonPushed, true);
            app.loadBtn.Icon = 'open.png';
            app.loadBtn.Text = '';
            app.Button_2 = uibutton(app.uipanel4, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'close.png';
            app.Button_2.Enable = 'off';
            app.Button_2.Text = '';
            app.openBtn = uibutton(app.uipanel4, 'push');
            app.openBtn.ButtonPushedFcn = createCallbackFcn(app, @openBtnButtonPushed, true);
            app.openBtn.Icon = 'new.png';
            app.openBtn.Text = '';
            app.addBtn = uibutton(app.uipanel4, 'push');
            app.addBtn.ButtonPushedFcn = createCallbackFcn(app, @addBtnButtonPushed, true);
            app.addBtn.Icon = 'add.png';
            app.addBtn.Enable = 'off';
            app.addBtn.Text = '';
            app.checkbox2 = uicheckbox(app.figure1);
            app.checkbox2.ValueChangedFcn = createCallbackFcn(app, @checkbox2_Callback, true);
            app.checkbox2.Text = 'Show Slice';
            app.Panel_2 = uipanel(app.figure1);
            app.Panel_2.Visible = 'off';
            app.logo = uiaxes(app.Panel_2);
            app.Panel_3 = uipanel(app.figure1);
            app.Panel_3.Visible = 'off';
            app.slider1 = uislider(app.Panel_3);
            app.slider1.Limits = [0 1];
            app.slider1.MajorTicks = [];
            app.slider1.ValueChangedFcn = createCallbackFcn(app, @slider1_Callback, true);
            app.slider1.MinorTicks = [];
            app.text1 = uilabel(app.Panel_3);
            app.text1.Text = 'Smooth';
            app.slider2 = uislider(app.Panel_3);
            app.slider2.Limits = [0 1];
            app.slider2.MajorTicks = [];
            app.slider2.ValueChangedFcn = createCallbackFcn(app, @slider2_Callback, true);
            app.slider2.MinorTicks = [];
            app.text2 = uilabel(app.Panel_3);
            app.text2.Text = 'Clean';
            app.slider3 = uislider(app.Panel_3);
            app.slider3.Limits = [0 1];
            app.slider3.MajorTicks = [];
            app.slider3.ValueChangedFcn = createCallbackFcn(app, @slider3_Callback, true);
            app.slider3.MinorTicks = [];
            app.text3 = uilabel(app.Panel_3);
            app.text3.Text = 'Transparency';
            app.Switch = uiswitch(app.Panel_3, 'slider');
            app.Switch.Items = {'', 'Off'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @atf_off, true);
            app.Switch.Value = '';
            app.showL = uicheckbox(app.Panel_3);
            app.showL.ValueChangedFcn = createCallbackFcn(app, @showLValueChanged, true);
            app.showL.Visible = 'off';
            app.showL.Text = 'L';
            app.showL.Value = true;
            app.showR = uicheckbox(app.Panel_3);
            app.showR.ValueChangedFcn = createCallbackFcn(app, @showRValueChanged, true);
            app.showR.Visible = 'off';
            app.showR.Text = 'R';
            app.showR.Value = true;
            app.showMap = uicheckbox(app.Panel_3);
            app.showMap.ValueChangedFcn = createCallbackFcn(app, @showMapValueChanged, true);
            app.showMap.Visible = 'off';
            app.showMap.Text = 'Spatial map';
            app.Panel_4 = uipanel(app.figure1);
            app.plottab = uitabgroup(app.Panel_4);
            app.plot1 = uitab(app.plottab);
            app.axes1 = uiaxes(app.plot1);
            app.axes1.XLim = [0 1];
            app.axes1.YLim = [0 1];
            app.axes1.ZLim = [0 1];
            app.axes1.CLim = [0 1];
            app.axes1.ALim = [0 1];
            app.axes1.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.axes1.XTickLabel = {'0  '; '0.2'; '0.4'; '0.6'; '0.8'; '1  '};
            app.axes1.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.axes1.YTickLabel = {'0  '; '0.2'; '0.4'; '0.6'; '0.8'; '1  '};
            app.axes1.ZTick = [0 0.5 1];
            app.axes1.ZTickLabel = '';
            app.pushbutton9 = uibutton(app.plot1, 'push');
            app.pushbutton9.ButtonPushedFcn = createCallbackFcn(app, @pushbutton9_Callback, true);
            app.pushbutton9.Icon = 'up.png';
            app.pushbutton9.Visible = 'off';
            app.pushbutton9.Text = '';
            app.pushbutton10 = uibutton(app.plot1, 'push');
            app.pushbutton10.ButtonPushedFcn = createCallbackFcn(app, @pushbutton10_Callback, true);
            app.pushbutton10.Icon = 'down.png';
            app.pushbutton10.Visible = 'off';
            app.pushbutton10.Text = '';
            app.Button = uibutton(app.plot1, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'window-icon.png';
            app.Button.Visible = 'off';
            app.Button.Text = '';
            app.tfpanel = uipanel(app.figure1);
            app.tfpanel.Title = 'Time Frequency Map';
            app.tfpanel.Visible = 'off';
            app.ConditionDropDownLabel = uilabel(app.tfpanel);
            app.ConditionDropDownLabel.Text = 'Condition';
            app.ConditionDropDown = uidropdown(app.tfpanel);
            app.ConditionDropDown.Items = {'', '', '', ''};
            app.ConditionDropDown.ValueChangedFcn = createCallbackFcn(app, @ConditionDropDownValueChanged, true);
            app.ConditionDropDown.Value = '';
            app.tfaxes = uiaxes(app.tfpanel);
            xlabel(app.tfaxes, 'Time')
            ylabel(app.tfaxes, 'Frequency')
            app.Button_4 = uibutton(app.tfpanel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '-';
            app.Button_5 = uibutton(app.tfpanel, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '+';
            app.Button_6 = uibutton(app.tfpanel, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '>>';
            app.Panel_6 = uipanel(app.figure1);
            app.Panel_6.Visible = 'off';
            app.axes3 = uiaxes(app.Panel_6);
            app.Button_3 = uibutton(app.Panel_6, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = 'light-on.png';
            app.Button_3.Text = '';
            app.panel2 = uipanel(app.figure1);
            app.panel2.Title = 'MRI Slice';
            app.panel2.Visible = 'off';
            app.TextArea = uitextarea(app.figure1);
            app.TextArea.Editable = 'off';
            app.TextArea.Visible = 'off';
            app.TabGroup = uitabgroup(app.figure1);
            app.ForfutureuseTab = uitab(app.TabGroup);
            app.ForfutureuseTab.Title = 'For future use';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '....';
            app.Panel_7 = uipanel(app.figure1);
            app.Panel_7.Visible = 'off';
            app.Panel_10 = uipanel(app.figure1);
            app.Panel_10.Visible = 'off';
            app.Panel_15 = uipanel(app.figure1);
            app.Panel_15.Visible = 'off';
            app.Panel_16 = uipanel(app.figure1);
            app.Panel_16.Visible = 'off';
            app.Panel_17 = uipanel(app.figure1);
            app.Panel_17.Visible = 'off';
            app.Panel_18 = uipanel(app.figure1);
            app.Panel_18.Visible = 'off';
            app.TabGroup2 = uitabgroup(app.figure1);
            app.TabGroup2.Visible = 'off';
            app.PlotsTab = uitab(app.TabGroup2);
            app.PlotsTab.Title = 'Plots';
            app.text9 = uilabel(app.PlotsTab);
            app.text9.Text = 'Electrodes';
            app.text4 = uilabel(app.PlotsTab);
            app.text4.Text = 'Baseline ';
            app.popupmenu1 = uidropdown(app.PlotsTab);
            app.popupmenu1.Items = {'Zscore', 'Log'};
            app.popupmenu1.ValueChangedFcn = createCallbackFcn(app, @popupmenu1_Callback, true);
            app.popupmenu1.Value = 'Zscore';
            app.text4_2 = uilabel(app.PlotsTab);
            app.text4_2.Text = 'Color';
            app.popupmenu1_2 = uidropdown(app.PlotsTab);
            app.popupmenu1_2.Items = {'Set1', 'Set2', 'Set3', 'Set4', 'Paired', 'Parula'};
            app.popupmenu1_2.ValueChangedFcn = createCallbackFcn(app, @popupmenu1_2ValueChanged, true);
            app.popupmenu1_2.Value = 'Set1';
            app.listbox1 = uilistbox(app.PlotsTab);
            app.listbox1.Items = {};
            app.listbox1.ValueChangedFcn = createCallbackFcn(app, @listbox1_Callback, true);
            app.listbox1.Value = {};
            app.ShowStatsCheckBox = uicheckbox(app.PlotsTab);
            app.ShowStatsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowStatsCheckBoxValueChanged, true);
            app.ShowStatsCheckBox.Text = 'Show Stats';
            app.ConditionsTab = uitab(app.TabGroup2);
            app.ConditionsTab.Title = 'Conditions';
        end
    end

    methods (Access = public)

        % Construct app
        function app = plot_window_App(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)plot_window_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end